classdef satplan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MasterGridLayout                matlab.ui.container.GridLayout
        MasterPlanOverviewPanel         matlab.ui.container.Panel
        MasterPlanGridLayout            matlab.ui.container.GridLayout
        VerboseCheckBox                 matlab.ui.control.CheckBox
        ClearButton                     matlab.ui.control.Button
        MasterPlanRemoveButton          matlab.ui.control.Button
        EventConsoleLogTextArea         matlab.ui.control.TextArea
        MasterPlanTable                 matlab.ui.control.Table
        MasterUIScaleSlider             matlab.ui.control.Slider
        MasterTabGroup                  matlab.ui.container.TabGroup
        SatelliteTab                    matlab.ui.container.Tab
        SatelliteTabLayout              matlab.ui.container.GridLayout
        AddSatellitePanel               matlab.ui.container.Panel
        AddSatellitePanelLayout         matlab.ui.container.GridLayout
        AddSatelliteTabGroup            matlab.ui.container.TabGroup
        AddSatelliteFromTLETab          matlab.ui.container.Tab
        AddSatelliteFromTLETabLayout    matlab.ui.container.GridLayout
        TLEFromURLExactMatchCheckBox    matlab.ui.control.CheckBox
        TLEFromFileExactMatchCheckBox   matlab.ui.control.CheckBox
        TLETextTextArea                 matlab.ui.control.TextArea
        TLETextTextAreaLabel            matlab.ui.control.Label
        TLEFromURLSearchTextArea        matlab.ui.control.TextArea
        SearchTextArea_2Label           matlab.ui.control.Label
        TLEFromURLEditField             matlab.ui.control.EditField
        TLEURLEditFieldLabel            matlab.ui.control.Label
        TLEFromURLLoadButton            matlab.ui.control.Button
        TLEFromFileSearchTextArea       matlab.ui.control.TextArea
        SearchTextAreaLabel             matlab.ui.control.Label
        TLEFromFileOpenButton           matlab.ui.control.Button
        TLEFileEditField                matlab.ui.control.EditField
        AddSatelliteFromTLEButton       matlab.ui.control.Button
        TLEFileEditFieldLabel           matlab.ui.control.Label
        AddSatelliteFromKeplerianElementsTab  matlab.ui.container.Tab
        AddSatelliteFromKeplerianElementsTabLayout  matlab.ui.container.GridLayout
        AddSatelliteFromKeplerianElementsNameEditField  matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        AddSatelliteFromKeplerianElementsEpochDate  matlab.ui.control.DatePicker
        AddSatelliteFromKeplerianElementsEpochTime  matlab.ui.control.EditField
        EpochUTCDatePickerLabel         matlab.ui.control.Label
        AddSatelliteFromKeplerianElementsPresetDropdown  matlab.ui.control.DropDown
        AddSatelliteFromKeplerianElementsSemimajorAxis  matlab.ui.control.NumericEditField
        AddSatelliteFromKeplerianElementsEccentricity  matlab.ui.control.NumericEditField
        AddSatelliteFromKeplerianElementsInclination  matlab.ui.control.NumericEditField
        InclinationdegEditField_2Label  matlab.ui.control.Label
        TrueAnomalydegEditFieldLabel    matlab.ui.control.Label
        AddSatelliteFromKeplerianElementsRAAN  matlab.ui.control.NumericEditField
        AddSatelliteFromKeplerianElementsArgofPeriapsis  matlab.ui.control.NumericEditField
        ArgofPerigeedegLabel            matlab.ui.control.Label
        RAANdegEditField_2Label         matlab.ui.control.Label
        EccentricityEditField_2Label    matlab.ui.control.Label
        AddSatelliteFromKeplerianElementsTrueAnomaly  matlab.ui.control.NumericEditField
        AddSatelliteFromKeplerianElementsButton  matlab.ui.control.Button
        SemimajorAxiskmEditFieldLabel   matlab.ui.control.Label
        OrbitPresetLabel                matlab.ui.control.Label
        AddSatelliteFromSGP4ElementsTab  matlab.ui.container.Tab
        AddSatelliteFromSGP4ElementsTabLayout  matlab.ui.container.GridLayout
        AddSatelliteFromSGP4ElementsNameEditField  matlab.ui.control.EditField
        B1EarthRadiusEditFieldLabel     matlab.ui.control.Label
        NameEditField_2Label            matlab.ui.control.Label
        AddSatelliteFromSGP4ElementsEpochDate  matlab.ui.control.DatePicker
        AddSatelliteFromSGP4ElementsEpochTime  matlab.ui.control.EditField
        EpochUTCDatePicker_2Label       matlab.ui.control.Label
        AddSatelliteFromSGP4ElementsPresetDropDown  matlab.ui.control.DropDown
        AddSatelliteFromSGP4ElementsMeanMotion  matlab.ui.control.NumericEditField
        AddSatelliteFromSGP4ElementsEccentricity  matlab.ui.control.NumericEditField
        AddSatelliteFromSGP4ElementsInclination  matlab.ui.control.NumericEditField
        InclinationdegEditField_3Label  matlab.ui.control.Label
        MeanAnomalydegEditField_2Label  matlab.ui.control.Label
        AddSatelliteFromSGP4ElementsRAAN  matlab.ui.control.NumericEditField
        AddSatelliteFromSGP4ElementsArgofPeriapsis  matlab.ui.control.NumericEditField
        ArgofPerigeedegLabel_2          matlab.ui.control.Label
        RAANdegEditField_3Label         matlab.ui.control.Label
        EccentricityEditField_3Label    matlab.ui.control.Label
        MeanMotionrevdayLabel           matlab.ui.control.Label
        AddSatelliteFromSGP4ElementsMeanAnomaly  matlab.ui.control.NumericEditField
        AddSatelliteFromSGP4ElementsBStar  matlab.ui.control.NumericEditField
        AddSatelliteFromSGP4ElementsButton  matlab.ui.control.Button
        OrbitPresetLabel_2              matlab.ui.control.Label
        LoadedSatellitesPanel           matlab.ui.container.Panel
        LoadedSatellitesPanelLayout     matlab.ui.container.GridLayout
        LoadedSatellitesTable           matlab.ui.control.Table
        SatelliteTabStatusLabel         matlab.ui.control.Label
        SatelliteTabUIVerticalSlider    matlab.ui.control.Slider
        SatelliteTabUIHorizontalSlider  matlab.ui.control.Slider
        SatelliteInfoPanel              matlab.ui.container.Panel
        SatelliteInfoPanelLayout        matlab.ui.container.GridLayout
        SatelliteInfoNameEditField      matlab.ui.control.EditField
        SatelliteNameEditFieldLabel     matlab.ui.control.Label
        SatelliteInfoEpochDatePicker    matlab.ui.control.DatePicker
        EpochUTCDatePickerLabel_2       matlab.ui.control.Label
        SatelliteInfoTabGroup           matlab.ui.container.TabGroup
        TLEDataTab                      matlab.ui.container.Tab
        TLEDataTabLayout                matlab.ui.container.GridLayout
        IgnoreChecksumsCheckBox         matlab.ui.control.CheckBox
        AutogenerateChecksumsCheckBox   matlab.ui.control.CheckBox
        TLEDataTabTLELinesLayout        matlab.ui.container.GridLayout
        TLEDataTabTLELine1TextEditField  matlab.ui.control.EditField
        TLEDataTabTLELine2TextEditField  matlab.ui.control.EditField
        Line2TextEditFieldLabel         matlab.ui.control.Label
        Line1TextEditFieldLabel         matlab.ui.control.Label
        TLEDataLine1Panel               matlab.ui.container.Panel
        TLEDataLine1PanelLayout         matlab.ui.container.GridLayout
        TLEDataLine1CatalogNumEditField  matlab.ui.control.NumericEditField
        Checksummod10EditFieldLabel     matlab.ui.control.Label
        ElementSetNumberEditFieldLabel  matlab.ui.control.Label
        TLEDataLine1ClassificationEditField  matlab.ui.control.EditField
        TLEDataLine1LaunchYrEditField   matlab.ui.control.NumericEditField
        TLEDataLine1LaunchNumEditField  matlab.ui.control.NumericEditField
        TLEDataLine1LaunchPieceEditField  matlab.ui.control.EditField
        BallisticCoefficientB1r_EarthEditFieldLabel  matlab.ui.control.Label
        SecondDerivofMeanMotionEditFieldLabel  matlab.ui.control.Label
        FirstDerivofMeanMotionEditFieldLabel  matlab.ui.control.Label
        TLEDataLine1EpochYearEditField  matlab.ui.control.NumericEditField
        TLEDataLine1EpochDayEditField   matlab.ui.control.NumericEditField
        EpochDayEditFieldLabel          matlab.ui.control.Label
        EpochYearEditFieldLabel         matlab.ui.control.Label
        IntlDesignatorlaunchnoEditFieldLabel  matlab.ui.control.Label
        IntlDesignatorlaunchyrEditFieldLabel  matlab.ui.control.Label
        SatelliteCatalogNumberEditField_3Label  matlab.ui.control.Label
        TLEDataLine1FirstDerivofMeanMotionEditField  matlab.ui.control.NumericEditField
        TLEDataLine1SecondDerivofMeanMotionEditField  matlab.ui.control.NumericEditField
        TLEDataLine1BStarEditField      matlab.ui.control.NumericEditField
        TLEDataLine1EphemerisTypeEditField  matlab.ui.control.EditField
        TLEDataLine1ElementSetNumberEditField  matlab.ui.control.NumericEditField
        TLEDataLine1ChecksumEditField   matlab.ui.control.NumericEditField
        EphemerisTypeEditFieldLabel     matlab.ui.control.Label
        IntlDesignatorpieceEditFieldLabel  matlab.ui.control.Label
        ClassificationEditFieldLabel    matlab.ui.control.Label
        TLEDataLine2Panel               matlab.ui.container.Panel
        TLELine2Layout                  matlab.ui.container.GridLayout
        TLEDataLine2CatalogNumEditField  matlab.ui.control.NumericEditField
        SatelliteCatalogNumberEditField_3Label_2  matlab.ui.control.Label
        TLEDataLine2InclinationEditField  matlab.ui.control.NumericEditField
        Checksummod10EditField_2Label   matlab.ui.control.Label
        RevNoatEpochEditFieldLabel      matlab.ui.control.Label
        MeanMotionrevdayEditFieldLabel  matlab.ui.control.Label
        TLEDataLine2RAANEditField       matlab.ui.control.NumericEditField
        TLEDataLine2EccentricityEditField  matlab.ui.control.NumericEditField
        TLEDataLine2ArgofPerigeeEditField  matlab.ui.control.NumericEditField
        TLEDataLine2MeanAnomalyEditField  matlab.ui.control.NumericEditField
        MeanAnomalydegEditFieldLabel    matlab.ui.control.Label
        ArgofPerigeedegEditFieldLabel   matlab.ui.control.Label
        EccentricityEditField_3Label_2  matlab.ui.control.Label
        TLEDataLine2MeanMotionEditField  matlab.ui.control.NumericEditField
        TLEDataLine2RevNoatEpochEditField  matlab.ui.control.NumericEditField
        TLEDataLine2ChecksumEditField   matlab.ui.control.NumericEditField
        RAANdegEditField_3Label_2       matlab.ui.control.Label
        InclinationdegEditField_3Label_2  matlab.ui.control.Label
        KeplerianElementsTab            matlab.ui.container.Tab
        KeplerianElementsTabLayout      matlab.ui.container.GridLayout
        KeplerianElementsTabSemimajorAxisEditField  matlab.ui.control.NumericEditField
        SemimajorAxiskmEditFieldLabel_2  matlab.ui.control.Label
        TrueAnomalydegLabel             matlab.ui.control.Label
        KeplerianElementsTabInclinationEditField  matlab.ui.control.NumericEditField
        ArgumentofPerigeedegLabel       matlab.ui.control.Label
        KeplerianElementsTabRAANEditField  matlab.ui.control.NumericEditField
        KeplerianElementsTabEccentricityEditField  matlab.ui.control.NumericEditField
        EccentricityEditField_3Label_3  matlab.ui.control.Label
        RAANdegEditField_3Label_3       matlab.ui.control.Label
        KeplerianElementsTabArgumentOfPerigeeEditField  matlab.ui.control.NumericEditField
        KeplerianElementsTabTrueAnomalyEditField  matlab.ui.control.NumericEditField
        InclinationdegEditField_3Label_3  matlab.ui.control.Label
        GroundStationsTab               matlab.ui.container.Tab
        GroundStationsTabGridLayout     matlab.ui.container.GridLayout
        GroundStationsTabStatusLabel    matlab.ui.control.Label
        GroundStationsTabUIHorizontalSlider  matlab.ui.control.Slider
        GroundStationsTabUIVerticalSlider  matlab.ui.control.Slider
        PassoverEventsTablePanel        matlab.ui.container.Panel
        PassoverEventsTablePanelGridLayout  matlab.ui.container.GridLayout
        PassoverEventsTableSatellitesDisplayDropDown  matlab.ui.control.DropDown
        DisplayDropDown_2Label          matlab.ui.control.Label
        ExportButton_2                  matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        PassoverEventsTable             matlab.ui.control.Table
        PassoverEventsTableAddAllButton  matlab.ui.control.Button
        PassoverEventsTableAddtoPlanButton  matlab.ui.control.Button
        PassoverOptionsIgnorePassoversEditField  matlab.ui.control.NumericEditField
        PassoverOptionsIgnorePassoversUnitsDropDown  matlab.ui.control.DropDown
        PassoverOptionsIgnorePassoversCheckBox  matlab.ui.control.CheckBox
        PassoverTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        PassoverTableDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDown_2Label        matlab.ui.control.Label
        PassoverTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_2                matlab.ui.control.Label
        PassoverTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_2        matlab.ui.control.Label
        ShowNonPassoverWindowsCheckBox  matlab.ui.control.CheckBox
        PassoverOptionsPanel            matlab.ui.container.Panel
        PassoverOptionsPanelGridLayout  matlab.ui.container.GridLayout
        MinElevationdegEditField        matlab.ui.control.NumericEditField
        MinElevationdegEditFieldLabel   matlab.ui.control.Label
        AltitudemEditField              matlab.ui.control.NumericEditField
        AltitudemEditFieldLabel         matlab.ui.control.Label
        LongitudedegEditField           matlab.ui.control.NumericEditField
        LongitudedegEditFieldLabel      matlab.ui.control.Label
        LatitudedegEditField            matlab.ui.control.NumericEditField
        LatitudedegEditFieldLabel       matlab.ui.control.Label
        NameEditField_3                 matlab.ui.control.EditField
        NameEditField_3Label            matlab.ui.control.Label
        PassoverOptionsCalculatePassoversButton  matlab.ui.control.Button
        GroundStationsInfoPanel         matlab.ui.container.Panel
        GroundStationsInfoPanelGridLayout  matlab.ui.container.GridLayout
        GroundStationsInfoTableSaveAsButton  matlab.ui.control.Button
        GroundStationsInfoTableLoadButton  matlab.ui.control.Button
        GroundStationsInfoTable         matlab.ui.control.Table
        PropagationTab                  matlab.ui.container.Tab
        SGP4TabGridLayout               matlab.ui.container.GridLayout
        SGP4TabStatusLabel              matlab.ui.control.Label
        SGP4TabUIVerticalSlider         matlab.ui.control.Slider
        SGP4PropagatorOptionsPanel      matlab.ui.container.Panel
        SGP4PropagatorOptionsPanelGridLayout  matlab.ui.container.GridLayout
        PropagatorDropDown              matlab.ui.control.DropDown
        PropagatorDropDownLabel         matlab.ui.control.Label
        SGP4PropagatorUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel                  matlab.ui.control.Label
        SGP4PropagatorStartDateDatePicker  matlab.ui.control.DatePicker
        StartTimeUTCLabel               matlab.ui.control.Label
        SGP4PropagatorEndDateDatePicker  matlab.ui.control.DatePicker
        EndTimeUTCLabel                 matlab.ui.control.Label
        SGP4PropagatorTimestepUnitsDropDown  matlab.ui.control.DropDown
        SGP4PropagatorStartTimeEditField  matlab.ui.control.EditField
        SGP4PropagatorEndTimeEditField  matlab.ui.control.EditField
        SGP4PropagatorTimestepEditField  matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        SGP4PropagatorPropagateButton   matlab.ui.control.Button
        SGP4GroundtrackPlotPanel        matlab.ui.container.Panel
        SGP4TablePanel                  matlab.ui.container.Panel
        SGP4TableGridLayout             matlab.ui.container.GridLayout
        SGP4TableAddEphemerisButton     matlab.ui.control.Button
        SGP4TableExportButton           matlab.ui.control.Button
        SGP4TableLoadButton             matlab.ui.control.Button
        SGP4TableSatellitesDisplayDropDown  matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        SGP4TableHighlightEclipseCheckBox  matlab.ui.control.CheckBox
        SGP4TableHighlightSAACheckBox   matlab.ui.control.CheckBox
        SGP4TableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel          matlab.ui.control.Label
        SGP4TableCoordinatesFormatDropDown  matlab.ui.control.DropDown
        CoordinatesDropDownLabel        matlab.ui.control.Label
        SGP4TableUTCOffsetEditField     matlab.ui.control.EditField
        UTCOffsetEditField_2Label       matlab.ui.control.Label
        SGP4TableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        SGP4Table                       matlab.ui.control.Table
        TargetsTab                      matlab.ui.container.Tab
        TargetsTabGridLayout            matlab.ui.container.GridLayout
        TargetsTabStatusLabel           matlab.ui.control.Label
        TargetsTabUIVerticalSlider      matlab.ui.control.Slider
        TargetsTabUIHorizontalSlider    matlab.ui.control.Slider
        TargetObservationWindowsTablePanel  matlab.ui.container.Panel
        TargetObservationWindowsTablePanelGridLayout  matlab.ui.container.GridLayout
        TargetWindowsTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        TargetWindowsTableDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDownLabel          matlab.ui.control.Label
        TargetWindowsTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_3                matlab.ui.control.Label
        TargetWindowsTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_3        matlab.ui.control.Label
        TargetObservationWindowsTable   matlab.ui.control.Table
        TargetSchedulerOptionsPanel     matlab.ui.container.Panel
        TargetSchedulerOptionsPanelGridLayout  matlab.ui.container.GridLayout
        TargetOptionsSunConstraintEditField  matlab.ui.control.NumericEditField
        SunConstraintEditFieldLabel     matlab.ui.control.Label
        TargetOptionsMoonConstraintEditField  matlab.ui.control.NumericEditField
        MoonConstraintEditFieldLabel    matlab.ui.control.Label
        TargetOptionsElevationConstraintEditField  matlab.ui.control.NumericEditField
        ElevationConstraintEditFieldLabel  matlab.ui.control.Label
        TargetOptionsDayNightConstraintEditField  matlab.ui.control.NumericEditField
        DayNightConstraintEditFieldLabel  matlab.ui.control.Label
        TargetOptionsIgnoreWindowsUnitsDropDown  matlab.ui.control.DropDown
        TargetOptionsIgnoreWindowsEditField  matlab.ui.control.NumericEditField
        TargetOptionsIgnoreWindowsCheckBox  matlab.ui.control.CheckBox
        TargetSchedulerCalculateVisibilityButton  matlab.ui.control.Button
        TargetInfoPanel                 matlab.ui.container.Panel
        TargetInfoPanelGridLayout       matlab.ui.container.GridLayout
        TargetInfoDeleteButton          matlab.ui.control.Button
        TargetInfoSaveAsButton          matlab.ui.control.Button
        TargetInfoLoadButton            matlab.ui.control.Button
        TargetInfoTable                 matlab.ui.control.Table
        ExposuresTab                    matlab.ui.container.Tab
        ExposuresTabGridLayout          matlab.ui.container.GridLayout
        ExposuresTabStatusLabel         matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        ExposuresAddedTablePanel        matlab.ui.container.Panel
        ExposuresAddedTablePanelGridLayout  matlab.ui.container.GridLayout
        ExposuresAddedDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDown_3Label        matlab.ui.control.Label
        ExposuresAddedUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_4                matlab.ui.control.Label
        ExposuresAddedTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_4        matlab.ui.control.Label
        ExposuresAddedShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        ExposuresAddedTableRemoveButton  matlab.ui.control.Button
        ExposuresAddedTable             matlab.ui.control.Table
        ExposureOptionsPanel            matlab.ui.container.Panel
        ExposureOptionsPanelGridLayout  matlab.ui.container.GridLayout
        ExposureOptionsStartDateDatePicker  matlab.ui.control.DatePicker
        StartDateDatePickerLabel_2      matlab.ui.control.Label
        ExposureOptionsUTCOffsetEditField  matlab.ui.control.EditField
        UTCLabel                        matlab.ui.control.Label
        ExposureOptionsTargetNameEditField  matlab.ui.control.EditField
        TargetNameEditFieldLabel        matlab.ui.control.Label
        ExposureOptionsStartTimeEditField  matlab.ui.control.EditField
        StartTimeEditField_3Label       matlab.ui.control.Label
        ExposureOptionsAddExposureButton  matlab.ui.control.Button
        ExposuresAvailableWindowsTablePanel  matlab.ui.container.Panel
        ExposuresAvailableWindowsTablePanelGridLayout  matlab.ui.container.GridLayout
        ExposuresAvailableIgnoreWindowsUnitsDropDown  matlab.ui.control.DropDown
        ExposuresAvailableIgnoreWindowsEditField  matlab.ui.control.NumericEditField
        ExposuresAvailableIgnoreWindowsCheckBox  matlab.ui.control.CheckBox
        ExposuresAvailableWindowsTable  matlab.ui.control.Table
        ConflictResolutionTab           matlab.ui.container.Tab
        ConflictResolutionTabGridLayout  matlab.ui.container.GridLayout
        ConflictsPanel                  matlab.ui.container.Panel
        ConflictResolutionUnderConstructionPanelGridLayout  matlab.ui.container.GridLayout
        ConflictTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_5                matlab.ui.control.Label
        ConflictTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_5        matlab.ui.control.Label
        ConflictTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        ConflictTableDeleteEventButton  matlab.ui.control.Button
        ConflictTable                   matlab.ui.control.Table
        FinalizePlanTab                 matlab.ui.container.Tab
        FinalizePlanTabGridLayout       matlab.ui.container.GridLayout
        FinalizePlanUIHorizontalSlider  matlab.ui.control.Slider
        FinalizePlanUIVerticalSlider    matlab.ui.control.Slider
        FinalizePlanViewOptionsPanel    matlab.ui.container.Panel
        FinalizePlanTablePanel          matlab.ui.container.Panel
        FinalizePlanTablePanelGridLayout  matlab.ui.container.GridLayout
        AddChargingEventsCheckBox       matlab.ui.control.CheckBox
        FinalizePlanShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        FinalizePlanUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetEditField_2Label_2     matlab.ui.control.Label
        FinalizePlanShowUnallocatedTimesCheckBox  matlab.ui.control.CheckBox
        FinalizePlanTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_6        matlab.ui.control.Label
        FinalizePlanDisplayplanasDropDown  matlab.ui.control.DropDown
        DisplayplanasDropDownLabel      matlab.ui.control.Label
        FinalizePlanUITable             matlab.ui.control.Table
        FinalizePlanRemoveButton        matlab.ui.control.Button
        FinalizePlanPlotPanel           matlab.ui.container.Panel
        ExportJSONTab                   matlab.ui.container.Tab
        ExportJSONTabGridLayout         matlab.ui.container.GridLayout
        ExportJSONUIVerticalSlider      matlab.ui.control.Slider
        ExportJSONPreviewPanel          matlab.ui.container.Panel
        ExportJSONPreviewGridLayout     matlab.ui.container.GridLayout
        ExportJSONCommandDescriptionTextArea  matlab.ui.control.TextArea
        StoredCommandDescriptionoptionalTextAreaLabel  matlab.ui.control.Label
        ExportJSONPreview               matlab.ui.control.TextArea
        ExportJSONCommandCommentsTextArea  matlab.ui.control.TextArea
        StoredCommandCommentsoptionalLabel  matlab.ui.control.Label
        ExportJSONPanel                 matlab.ui.container.Panel
        ExportJSONPanelGridLayout       matlab.ui.container.GridLayout
        ExportJSONButton                matlab.ui.control.Button
        ExportJSONPlanCommentsoptionalTextArea  matlab.ui.control.TextArea
        ObservationPlanCommentsoptionalTextAreaLabel  matlab.ui.control.Label
        ExportJSONAuthorsEditField      matlab.ui.control.EditField
        AuthorsEditFieldLabel           matlab.ui.control.Label
        LoadedSatellitesTableContextMenu  matlab.ui.container.ContextMenu
        MoveMenu                        matlab.ui.container.Menu
        UpMenu                          matlab.ui.container.Menu
        DownMenu                        matlab.ui.container.Menu
        FirstMenu                       matlab.ui.container.Menu
        LastMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportAllMenu                   matlab.ui.container.Menu
        ConsoleLogContextMenu           matlab.ui.container.ContextMenu
        ShowMenu                        matlab.ui.container.Menu
        SuccessMenu                     matlab.ui.container.Menu
        WarningMenu                     matlab.ui.container.Menu
        ErrorMenu                       matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        SuccessMenu_2                   matlab.ui.container.Menu
        WarningMenu_2                   matlab.ui.container.Menu
        ErrorMenu_2                     matlab.ui.container.Menu
        AllMenu                         matlab.ui.container.Menu
        VerboseMenu                     matlab.ui.container.Menu
        ExportMenu_2                    matlab.ui.container.Menu
        TLETextAreaContextMenu          matlab.ui.container.ContextMenu
        ExportMenu_3                    matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        AutogeneratechecksumsMenu       matlab.ui.container.Menu
        IgnorechecksumsMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tleHandler
        satelliteScenarioHandler
        tabStateHandler
        eventLogHandler
    end

    properties (Access = private)

    end
    
    methods (Access = private)
        
        % TODO: clean up this structure a bit
        function update_all_tab_state_indicators(app)
            update_TLE_tab_status_label(app);
            update_SGP4_tab_status_label(app);
            update_ground_stations_tab_status_label(app);
            update_targets_tab_status_label(app);
            update_exposures_tab_status_label(app);
        end
        
        function update_state_indicator(app, label_to_update, new_state)

            new_background_color = new_state.Color;
            label_to_update.BackgroundColor = new_background_color;
            
        end
        
        function update_TLE_tab_status_label(app)
            update_state_indicator(app, app.SatelliteTabStatusLabel, app.tabStateHandler.SatelliteTabState)
        end
        
        function update_SGP4_tab_status_label(app)
            update_state_indicator(app, app.SGP4TabStatusLabel, app.tabStateHandler.PropagationTabState)
        end
        
        function update_ground_stations_tab_status_label(app)
            update_state_indicator(app, app.GroundStationsTabStatusLabel, app.tabStateHandler.AccessTabState)
        end
        
        function update_targets_tab_status_label(app)
            update_state_indicator(app, app.TargetsTabStatusLabel, app.tabStateHandler.TargetTabState)
        end
        
        function update_exposures_tab_status_label(app)
            update_state_indicator(app, app.ExposuresTabStatusLabel, app.tabStateHandler.ExposureTabState)
        end
        
        function orb_prop(app)
            % TODO: function events

            % Broadcast tab state event
            % TODO: more robust version of this
            app.tabStateHandler.sendTransitionEvent('PROPAGATION_CHANGED')

            app.satelliteScenarioHandler.resetSatelliteScenario();

            % Quickly write TLE file (hacky)
            file_name = 'test_output.tle';
            app.tleHandler.saveTLEToFile(file_name);
            
            % Build date vec from date and time picker
            UTC_offset_str = app.SGP4PropagatorUTCOffsetEditField.Value;
            SGP4_expected_timezone = 'UTC';  % don't change this!  
                    
            start_datetime = app.SGP4PropagatorStartDateDatePicker.Value;
            start_time_only = datetime(app.SGP4PropagatorStartTimeEditField.Value);
            start_datetime.Hour = start_time_only.Hour;
            start_datetime.Minute = start_time_only.Minute;
            start_datetime.Second = start_time_only.Second;
            start_datetime.TimeZone = UTC_offset_str;
            start_datetime.TimeZone = SGP4_expected_timezone;  % converting to UTC
            
            end_datetime = app.SGP4PropagatorEndDateDatePicker.Value;
            end_time_only = datetime(app.SGP4PropagatorEndTimeEditField.Value);
            end_datetime.Hour = end_time_only.Hour;
            end_datetime.Minute = end_time_only.Minute;
            end_datetime.Second = end_time_only.Second;
            end_datetime.TimeZone = UTC_offset_str;
            end_datetime.TimeZone = SGP4_expected_timezone;  % converting to UTC

            % Convert timestep value into minutes
            delta_units = app.SGP4PropagatorTimestepUnitsDropDown.Value;
            delta_value = app.SGP4PropagatorTimestepEditField.Value;
            if strcmp(delta_units,'milliseconds')
                delta_sec = delta_value/(1000*60);
            elseif strcmp(delta_units, 'seconds')
                delta_sec = delta_value;
            elseif strcmp(delta_units, 'minutes')
                delta_sec = delta_value*60;
            end

            % Set time constraints on satellite scenario
            app.satelliteScenarioHandler.ScenarioStartDatetime = start_datetime;
            app.satelliteScenarioHandler.ScenarioEndDatetime = end_datetime;
            app.satelliteScenarioHandler.ScenarioTimestepSizeSec = delta_sec;

            % Add satellite from TLE to satellite scenario
            % TODO: move this somewhere else?
            app.satelliteScenarioHandler.addSatelliteFromTLEFile(file_name);

            % Update dropdowns to reflect correct number of satellites
            num_satellites = length(app.satelliteScenarioHandler.Satellites);
            new_dropdown_entries = num_satellites+1;
            dropdown_entries = cell(1, new_dropdown_entries);
            dropdown_entries(1) = {'All'};
            dropdown_entries(2:end) = cellstr(num2str((1:num_satellites)'));
            app.SGP4TableSatellitesDisplayDropDown.Items = dropdown_entries;
            app.SGP4TableSatellitesDisplayDropDown.Value = dropdown_entries(1);
            app.PassoverEventsTableSatellitesDisplayDropDown.Items = dropdown_entries;
            app.PassoverEventsTableSatellitesDisplayDropDown.Value = dropdown_entries(1);

            % Open graphic
            app.satelliteScenarioHandler.displayGraphics();

            % Populate table
            app.updatePropagationTable();

            % Broadcast tab state event
            % TODO: more robust version of this
            app.tabStateHandler.sendTransitionEvent('PROPAGATION_CALCULATED')
            app.update_all_tab_state_indicators();
            

%             [LLA, times, sat_scenario_obj, sat_obj] = satellite_scenario_helper(start_datetime, end_datetime, delta, default_tle_filename);
% 
%             app.deltamin = delta*60;
%             app.LLA_all = LLA';
%             app.SGP4_times = times';
%             app.sat_scenario = sat_scenario_obj;
%             app.satellite_object = sat_obj;
            
%             % Create satellite scenario
%             sat_scen = satelliteScenario(start_datetime, end_datetime, app.deltamin);
%             app.sat_scenario = sat_scen;
%             
%             % Add satellite to scenario
%             sat_obj = satellite(sat_scen, default_tle_filename);
%             app.satellite_object = sat_obj;
% 
%             % Get resulting LLA
%             [LLA_2, ~, times_2] = states(app.satellite_object, 'CoordinateFrame', 'geographic');
%             app.LLA_all = LLA;
%             app.SGP4_times = times;

%             % Convert times to MJD for other functions (probably not needed
%             % later)
%             app.mjd_all = mjuliandate(app.SGP4_times);
% 
%             % Calculate SAA exclusion zones
%             calculate_SAA_exclusion_zones(app);
%             
%             % Calculate polar keepout zones
%             calculate_polar_keepout_zones(app);
%             
%             % Calculate night times (in eclipse with Earth)
%             calculate_eclipse_times(app);
%               
%             % Display LLA and times in main table
%             update_LLA_table(app);
%             
%             %Write ground track to KML file
%             LLA_with_times = [app.mjd_all, app.LLA_all];
%             KMLplot(app, LLA_with_times);
%             
%             % Update masterplan
%             update_masterplan(app);
%             
%             % Update GUI state
%             set_GUI_state_to_waiting_for_passovers(app);
            
        end
        
        function populateTLEValues(app, TLE_id_to_show)
            TLE_list = app.tleHandler.TLEHandles;

            if ~isempty(TLE_list) && ~isempty(TLE_id_to_show) && TLE_id_to_show ~= 0

                TLE_to_show = TLE_list(TLE_id_to_show);

                % Load all the variables from the TLE
                app.SatelliteInfoNameEditField.Value = TLE_to_show.SatelliteName;
                app.SatelliteInfoEpochDatePicker.Value = TLE_to_show.Epoch;
                app.TLEDataTabTLELine1TextEditField.Value = TLE_to_show.TLELine1;
                app.TLEDataTabTLELine2TextEditField.Value = TLE_to_show.TLELine2;

                % Line 1
                app.TLEDataLine1CatalogNumEditField.Value = TLE_to_show.CatalogNumber;
                app.TLEDataLine1ClassificationEditField.Value = TLE_to_show.Classification;
                app.TLEDataLine1LaunchYrEditField.Value = TLE_to_show.LaunchYearDesignator;
                app.TLEDataLine1LaunchNumEditField.Value = TLE_to_show.LaunchNumberDesignator;
                app.TLEDataLine1LaunchPieceEditField.Value = TLE_to_show.LaunchPieceDesignator;
                app.TLEDataLine1EpochYearEditField.Value = TLE_to_show.EpochYear;
                app.TLEDataLine1EpochDayEditField.Value = TLE_to_show.EpochDay;
                app.TLEDataLine1FirstDerivofMeanMotionEditField.Value = TLE_to_show.FirstDerivofMeanMotion;
                app.TLEDataLine1SecondDerivofMeanMotionEditField.Value = TLE_to_show.SecondDerivofMeanMotion;
                app.TLEDataLine1BStarEditField.Value = TLE_to_show.BStar;
                app.TLEDataLine1EphemerisTypeEditField.Value = TLE_to_show.EphemerisType;
                app.TLEDataLine1ElementSetNumberEditField.Value = TLE_to_show.ElementSetNum;
                app.TLEDataLine1ChecksumEditField.Value = TLE_to_show.ChecksumOne;

                % Line 2
                app.TLEDataLine2CatalogNumEditField.Value = TLE_to_show.CatalogNumber;
                app.TLEDataLine2InclinationEditField.Value = TLE_to_show.Inclination;
                app.TLEDataLine2RAANEditField.Value = TLE_to_show.RAAN;
                app.TLEDataLine2EccentricityEditField.Value = TLE_to_show.Eccentricity;
                app.TLEDataLine2ArgofPerigeeEditField.Value = TLE_to_show.ArgumentOfPeriapsis;
                app.TLEDataLine2MeanAnomalyEditField.Value = TLE_to_show.MeanAnomaly;
                app.TLEDataLine2MeanMotionEditField.Value = TLE_to_show.MeanMotion;
                app.TLEDataLine2RevNoatEpochEditField.Value  = TLE_to_show.RevolutionNoAtEpoch;
                app.TLEDataLine2ChecksumEditField.Value = TLE_to_show.ChecksumTwo;

                % Keplerian Elements
                app.KeplerianElementsTabSemimajorAxisEditField.Value = TLE_to_show.SemiMajorAxis / 1000;  % Note conversion to km
                app.KeplerianElementsTabInclinationEditField.Value = TLE_to_show.Inclination;
                app.KeplerianElementsTabRAANEditField.Value = TLE_to_show.RAAN;
                app.KeplerianElementsTabEccentricityEditField.Value = TLE_to_show.Eccentricity;
                app.KeplerianElementsTabArgumentOfPerigeeEditField.Value = TLE_to_show.ArgumentOfPeriapsis;
                app.KeplerianElementsTabTrueAnomalyEditField.Value = TLE_to_show.TrueAnomaly;

                % Allow fields to be editable
                app.SatelliteInfoNameEditField.Editable = true;
                app.SatelliteInfoEpochDatePicker.Editable = true;
                % Probably more trouble than it's worth to allow these to
                % be edited
                % app.TLEDataTabTLELine1TextEditField.Editable = true;
                % app.TLEDataTabTLELine2TextEditField.Editable = true;
                app.TLEDataLine1CatalogNumEditField.Editable = true;
                app.TLEDataLine1ClassificationEditField.Editable = true;
                app.TLEDataLine1LaunchYrEditField.Editable = true;
                app.TLEDataLine1LaunchNumEditField.Editable = true;
                app.TLEDataLine1LaunchPieceEditField.Editable = true;
                app.TLEDataLine1EpochYearEditField.Editable = true;
                app.TLEDataLine1EpochDayEditField.Editable = true;
                app.TLEDataLine1FirstDerivofMeanMotionEditField.Editable = true;
                app.TLEDataLine1SecondDerivofMeanMotionEditField.Editable = true;
                app.TLEDataLine1BStarEditField.Editable = true;
                app.TLEDataLine1EphemerisTypeEditField.Editable = true;
                app.TLEDataLine1ElementSetNumberEditField.Editable = true;
                app.TLEDataLine1ChecksumEditField.Editable = true;
                app.TLEDataLine2CatalogNumEditField.Editable = true;
                app.TLEDataLine2InclinationEditField.Editable = true;
                app.TLEDataLine2RAANEditField.Editable = true;
                app.TLEDataLine2EccentricityEditField.Editable = true;
                app.TLEDataLine2ArgofPerigeeEditField.Editable = true;
                app.TLEDataLine2MeanAnomalyEditField.Editable = true;
                app.TLEDataLine2MeanMotionEditField.Editable = true;
                app.TLEDataLine2RevNoatEpochEditField.Editable = true;
                app.TLEDataLine2ChecksumEditField.Editable = true;
                app.KeplerianElementsTabSemimajorAxisEditField.Editable = true;
                app.KeplerianElementsTabInclinationEditField.Editable = true;
                app.KeplerianElementsTabRAANEditField.Editable = true;
                app.KeplerianElementsTabEccentricityEditField.Editable = true;
                app.KeplerianElementsTabArgumentOfPerigeeEditField.Editable = true;
                app.KeplerianElementsTabTrueAnomalyEditField.Editable = true;


                % FIXME: doesn't work for edit fields
                % removeStyle(app.TLEDataLine1ChecksumEditField);
                % removeStyle(app.TLEDataLine2ChecksumEditField);
                % if ~app.tleHandler.IgnoreChecksums
                %     % Add styles on checksums
                %     table_style = StatusCode.Warning.TableStyle;
                %     addStyle(app.TLEDataLine1ChecksumEditField, table_style);
                %     addStyle(app.TLEDataLine2ChecksumEditField, table_style);
                % end

            else
                
                % Empty everything
                app.SatelliteInfoNameEditField.Value = '';
                app.SatelliteInfoEpochDatePicker.Value = NaT;
                app.TLEDataTabTLELine1TextEditField.Value = '';
                app.TLEDataTabTLELine2TextEditField.Value = '';

                % Line 1
                app.TLEDataLine1CatalogNumEditField.Value = [];
                app.TLEDataLine1ClassificationEditField.Value = '';
                app.TLEDataLine1LaunchYrEditField.Value = [];
                app.TLEDataLine1LaunchNumEditField.Value  = [];
                app.TLEDataLine1LaunchPieceEditField.Value = '';
                app.TLEDataLine1EpochYearEditField.Value = [];
                app.TLEDataLine1EpochDayEditField.Value  = [];
                app.TLEDataLine1FirstDerivofMeanMotionEditField.Value = [];
                app.TLEDataLine1SecondDerivofMeanMotionEditField.Value = [];
                app.TLEDataLine1BStarEditField.Value  = [];
                app.TLEDataLine1EphemerisTypeEditField.Value = '';
                app.TLEDataLine1ElementSetNumberEditField.Value = [];
                app.TLEDataLine1ChecksumEditField.Value = [];

                % Line 2
                app.TLEDataLine2CatalogNumEditField.Value = [];
                app.TLEDataLine2InclinationEditField.Value = [];
                app.TLEDataLine2RAANEditField.Value = [];
                app.TLEDataLine2EccentricityEditField.Value = [];
                app.TLEDataLine2ArgofPerigeeEditField.Value = [];
                app.TLEDataLine2MeanAnomalyEditField.Value  = [];
                app.TLEDataLine2MeanMotionEditField.Value = [];
                app.TLEDataLine2RevNoatEpochEditField.Value  = [];
                app.TLEDataLine2ChecksumEditField.Value = [];

                % Keplerian Elements
                app.KeplerianElementsTabSemimajorAxisEditField.Value = [];
                app.KeplerianElementsTabInclinationEditField.Value = [];
                app.KeplerianElementsTabRAANEditField.Value = [];
                app.KeplerianElementsTabEccentricityEditField.Value = [];
                app.KeplerianElementsTabArgumentOfPerigeeEditField.Value = [];
                app.KeplerianElementsTabTrueAnomalyEditField.Value = [];

                % Disable editing
                app.SatelliteInfoNameEditField.Editable = false;
                app.SatelliteInfoEpochDatePicker.Editable = false;
                % app.TLEDataTabTLELine1TextEditField.Editable = false;
                % app.TLEDataTabTLELine2TextEditField.Editable = false;
                app.TLEDataLine1CatalogNumEditField.Editable = false;
                app.TLEDataLine1ClassificationEditField.Editable = false;
                app.TLEDataLine1LaunchYrEditField.Editable = false;
                app.TLEDataLine1LaunchNumEditField.Editable = false;
                app.TLEDataLine1LaunchPieceEditField.Editable = false;
                app.TLEDataLine1EpochYearEditField.Editable = false;
                app.TLEDataLine1EpochDayEditField.Editable = false;
                app.TLEDataLine1FirstDerivofMeanMotionEditField.Editable = false;
                app.TLEDataLine1SecondDerivofMeanMotionEditField.Editable = false;
                app.TLEDataLine1BStarEditField.Editable = false;
                app.TLEDataLine1EphemerisTypeEditField.Editable = false;
                app.TLEDataLine1ElementSetNumberEditField.Editable = false;
                app.TLEDataLine1ChecksumEditField.Editable = false;
                app.TLEDataLine2CatalogNumEditField.Editable = false;
                app.TLEDataLine2InclinationEditField.Editable = false;
                app.TLEDataLine2RAANEditField.Editable = false;
                app.TLEDataLine2EccentricityEditField.Editable = false;
                app.TLEDataLine2ArgofPerigeeEditField.Editable = false;
                app.TLEDataLine2MeanAnomalyEditField.Editable = false;
                app.TLEDataLine2MeanMotionEditField.Editable = false;
                app.TLEDataLine2RevNoatEpochEditField.Editable = false;
                app.TLEDataLine2ChecksumEditField.Editable = false;
                app.KeplerianElementsTabSemimajorAxisEditField.Editable = false;
                app.KeplerianElementsTabInclinationEditField.Editable = false;
                app.KeplerianElementsTabRAANEditField.Editable = false;
                app.KeplerianElementsTabEccentricityEditField.Editable = false;
                app.KeplerianElementsTabArgumentOfPerigeeEditField.Editable = false;
                app.KeplerianElementsTabTrueAnomalyEditField.Editable = false;

                % FIXME: doesn't work for edit fields
                % % Remove any styles on checksums
                % removeStyle(app.TLEDataLine1ChecksumEditField);
                % removeStyle(app.TLEDataLine2ChecksumEditField);

            end
        end
        
        function populateLoadedSatellitesTable(app)
            % Load TLEHandles
            TLE_list = app.tleHandler.TLEHandles;

            new_table = string();

            % Load the data
            for i = 1:numel(TLE_list)

                % Load TLE object
                TLE = TLE_list(i);

                % Create new table
                new_table(i, 1) = TLE.SatelliteName;
                new_table(i, 2) = num2str(TLE.Inclination);
                new_table(i, 3) = num2str(TLE.RAAN);
                new_table(i, 4) = num2str(TLE.Eccentricity);
                new_table(i, 5) = num2str(TLE.ArgumentOfPeriapsis);
                new_table(i, 6) = num2str(TLE.MeanOrbitalPeriod);
            end

            % Load newly created table and update the GUI
            app.LoadedSatellitesTable.Data = new_table;
        end

        function updateEventLogText(app)

            new_text = app.eventLogHandler.generateConsoleLogText();
            app.EventConsoleLogTextArea.Value = new_text;
            
        end
        
        function addNewEventLogEvents(app, new_events)

            for i = 1:length(new_events)
                app.eventLogHandler.addGUIEvent(new_events(i));
            end
            app.updateEventLogText();
            
        end
        
        function clearConsoleLog(app)
            
            app.eventLogHandler.deleteAllEvents();
            app.updateEventLogText();

        end
        
        function updateLoadedSatellitesTable(app)

            previous_selection = app.LoadedSatellitesTable.Selection;
            % previous_sorting = app.LoadedSatellitesTable.??;

            [new_table_data, warning_rows, function_events] = app.tleHandler.generateSatellitesTableData();

            if ~isempty(new_table_data)

                % Assign table data
                app.LoadedSatellitesTable.Data = new_table_data;
                new_column_names = new_table_data.Properties.VariableNames;
                app.LoadedSatellitesTable.ColumnName = new_column_names;
                % Re-assign last selection
                app.LoadedSatellitesTable.Selection = previous_selection;

                % Set colors for warning and error rows
                removeStyle(app.LoadedSatellitesTable);
                if any(warning_rows)
                    warning_style = StatusCode.Warning.TableStyle;
                    addStyle(app.LoadedSatellitesTable, warning_style, 'row', warning_rows);
                end

            end

            app.addNewEventLogEvents(function_events);
            
        end
        
        function initializeTLEData(app)

            % Dear MathWorks: please make this easier to do

            % Allow line 1 variables to be empty, and set them as such
            app.TLEDataLine1CatalogNumEditField.AllowEmpty = 'on';
            app.TLEDataLine1CatalogNumEditField.Value = [];
            app.TLEDataLine1LaunchYrEditField.AllowEmpty = 'on';
            app.TLEDataLine1LaunchYrEditField.Value = [];
            app.TLEDataLine1LaunchNumEditField.AllowEmpty = 'on';
            app.TLEDataLine1LaunchNumEditField.Value = [];
            app.TLEDataLine1EpochYearEditField.AllowEmpty = 'on';
            app.TLEDataLine1EpochYearEditField.Value = [];
            app.TLEDataLine1EpochDayEditField.AllowEmpty = 'on';
            app.TLEDataLine1EpochDayEditField.Value = [];
            app.TLEDataLine1FirstDerivofMeanMotionEditField.AllowEmpty = 'on';
            app.TLEDataLine1FirstDerivofMeanMotionEditField.Value = [];
            app.TLEDataLine1SecondDerivofMeanMotionEditField.AllowEmpty = 'on';
            app.TLEDataLine1SecondDerivofMeanMotionEditField.Value = [];
            app.TLEDataLine1BStarEditField.AllowEmpty = 'on';
            app.TLEDataLine1BStarEditField.Value = [];
            app.TLEDataLine1ElementSetNumberEditField.AllowEmpty = 'on';
            app.TLEDataLine1ElementSetNumberEditField.Value = [];
            app.TLEDataLine1ChecksumEditField.AllowEmpty = 'on';
            app.TLEDataLine1ChecksumEditField.Value = [];

            % Allow line 2 variables to be empty, and set them as such
            app.TLEDataLine2CatalogNumEditField.AllowEmpty = 'on';
            app.TLEDataLine2CatalogNumEditField.Value = [];
            app.TLEDataLine2InclinationEditField.AllowEmpty = 'on';
            app.TLEDataLine2InclinationEditField.Value = [];
            app.TLEDataLine2RAANEditField.AllowEmpty = 'on';
            app.TLEDataLine2RAANEditField.Value = [];
            app.TLEDataLine2EccentricityEditField.AllowEmpty = 'on';
            app.TLEDataLine2EccentricityEditField.Value = [];
            app.TLEDataLine2ArgofPerigeeEditField.AllowEmpty = 'on';
            app.TLEDataLine2ArgofPerigeeEditField.Value = [];
            app.TLEDataLine2MeanAnomalyEditField.AllowEmpty = 'on';
            app.TLEDataLine2MeanAnomalyEditField.Value = [];
            app.TLEDataLine2MeanMotionEditField.AllowEmpty = 'on';
            app.TLEDataLine2MeanMotionEditField.Value = [];
            app.TLEDataLine2RevNoatEpochEditField.AllowEmpty = 'on';
            app.TLEDataLine2RevNoatEpochEditField.Value = [];
            app.TLEDataLine2ChecksumEditField.AllowEmpty = 'on';
            app.TLEDataLine2ChecksumEditField.Value = [];

            % Allow Keplerian elements to be empty, and set them as such
            app.KeplerianElementsTabSemimajorAxisEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabSemimajorAxisEditField.Value = [];
            app.KeplerianElementsTabInclinationEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabInclinationEditField.Value = [];
            app.KeplerianElementsTabRAANEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabRAANEditField.Value = [];
            app.KeplerianElementsTabEccentricityEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabEccentricityEditField.Value = [];
            app.KeplerianElementsTabArgumentOfPerigeeEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabArgumentOfPerigeeEditField.Value = [];
            app.KeplerianElementsTabTrueAnomalyEditField.AllowEmpty = 'on';
            app.KeplerianElementsTabTrueAnomalyEditField.Value = [];
            
        end
        
        function updatePropagationTable(app)

            % Find options for data
            satellites_to_show = app.SGP4TableSatellitesDisplayDropDown.Value;
            if strcmpi(satellites_to_show, 'All')
                total_num_satellites = length(app.satelliteScenarioHandler.Satellites);
                satellites_to_show = 1:total_num_satellites;
            end
            coordinates_format = app.SGP4TableCoordinatesFormatDropDown.Value;
            timestamp_format = app.SGP4TableTimestampFormatDropDown.Value;

            [new_table_data, function_events] = app.satelliteScenarioHandler.generatePropagationTableData( ...
                satellites_to_show, coordinates_format);%, timestamp_format);

            if ~isempty(new_table_data)

                % Assign table data
                app.SGP4Table.Data = new_table_data;
                new_column_names = new_table_data.Properties.VariableNames;
                app.SGP4Table.ColumnName = new_column_names;

            end

            app.addNewEventLogEvents(function_events);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initialize handler objects
            app.tleHandler = TLEHandler();
            app.satelliteScenarioHandler = SatelliteScenarioHandler();
            app.tabStateHandler = TabStateHandler();
            app.eventLogHandler = GUIEventLog();
            app.initializeTLEData();

            % Testing some stuff
            app.TLEDataLine1CatalogNumEditField.AllowEmpty = 'on';
            app.TLEDataLine1CatalogNumEditField.Value = [];


            % Broadcast initialization is complete
            % TODO: handle event strings as app properties
            app.tabStateHandler.sendTransitionEvent('GUI_INITIALIZATION_COMPLETE');
            app.update_all_tab_state_indicators();

        end

        % Button pushed function: SGP4PropagatorPropagateButton
        function SGP4PropagatorPropagateButtonPushed(app, event)
            % TODO: rewrite this function in more canonical style
            orb_prop(app);

            % If successful, allow user to calculate passovers
            app.PassoverOptionsCalculatePassoversButton.Enable = true;
            app.TargetSchedulerCalculateVisibilityButton.Enable = true;
            
%             % Update groundtrack plot to match start/end time inputted to
%             % SGP4 (effectively plotting all data)
%             app.SGP4GroundtrackPlotPlotfromDatePicker.Value = app.SGP4PropagatorStartDateDatePicker.Value;
%             app.SGP4GroundtrackPlotPlottoDatePicker.Value = app.SGP4PropagatorEndDateDatePicker.Value;
%             app.SGP4GroundtrackPlotStartTimeEditField.Value = app.SGP4PropagatorStartTimeEditField.Value;
%             app.SGP4GroundtrackPlotEndTimeEditField.Value = app.SGP4PropagatorEndTimeEditField.Value;
%             update_SGP4_groundtrack_plot(app);
%             % Update masterplan plot to match start/end time inputted to
%             % SGP4 (effectively plotting all data)
%             app.FinalizePlanPlotPlotfromDatePicker.Value = app.SGP4PropagatorStartDateDatePicker.Value;
%             app.FinalizePlanPlotPlottoDatePicker.Value = app.SGP4PropagatorEndDateDatePicker.Value;
%             app.FinalizePlanPlotStartTimeEditField.Value = app.SGP4PropagatorStartTimeEditField.Value;
%             app.FinalizePlanPlotEndTimeEditField.Value = app.SGP4PropagatorEndTimeEditField.Value;
%             update_masterplan_groundtrack_plot(app);
            
            % Match given UTC offset in LLA table
            app.SGP4TableUTCOffsetEditField.Value = app.SGP4PropagatorUTCOffsetEditField.Value;
        end

        % Button pushed function: TLEFromFileOpenButton
        function TLEFromFileOpenButtonPushed(app, event)
       
            % TODO: remove a lot of the overlapping code between this and
            % URL version

            % Prompt user for file
            allowed_file_types = {'*.tle';'*.txt';'*.*'};
            window_prompt = 'Pick a TLE file';
            % This returns 0 for file if the user exits the window
            [file, path] = uigetfile( ...
                allowed_file_types, window_prompt);

            % Check if the user selected a file
            if isequal(file, 0)

                % If no file selcted, create a message for the console log
                event_message = 'User selected cancel, no TLE data loaded.';
                new_event = ConsoleEvent(event_message);
                app.addNewEventLogEvents(new_event);

                % Exit the function
                return
            
            else

                % If the user successfully selected a file...
                try
                    % ... try to build the full path
                    TLE_source = fullfile(path, file);
                
                    % Set the full path in the file edit field
                    app.TLEFileEditField.Value = TLE_source;

                    % If successful, create a message for the console log
                    event_message = sprintf('User selected file %s.', TLE_source);
                    new_event = ConsoleEvent(event_message);
                    app.addNewEventLogEvents(new_event);
                
                catch e

                    % If unsuccessful, create a message for the console log
                    event_message = sprintf('Could not find file %s at path %s, no TLE data loaded.', path, file);
                    event_error_code = StatusCode.Error;
                    new_event = ConsoleEvent(event_message, event_error_code, e);
                    app.addNewEventLogEvents(new_event);

                    % Exit the function
                    return

                end

            end

            % Load text from file
            try
                
                % Load raw lines from file
                raw_text = readlines(TLE_source);
                raw_text = splitlines(raw_text);

                % Trim text of leading or trailing whitespace
                raw_text = strtrim(raw_text);

                % Add objects, allows for multiple TLEs to be added. They must
                % be separated by commas
                search_terms = app.TLEFromFileSearchTextArea.Value;
                exact_match_required = app.TLEFromFileExactMatchCheckBox.Value;

                % If search terms are present, find the relevant TLE data
                % from the raw text
                if ~isempty(search_terms{:})
                    
                    % Split lines 
                    if isequal(1, numel(search_terms))
                        search_terms = strip(split(search_terms, ','));
                    end

                    [raw_text, function_events] = app.tleHandler.findTLE(raw_text, search_terms, exact_match_required);
                    app.addNewEventLogEvents(function_events);

                end

                total_num_lines = length(raw_text);

                if total_num_lines == 0
                    event_message = sprintf('Unable to find TLE data in %s.', TLE_source);
                    event_error_code = StatusCode.Warning;
                    new_event = ConsoleEvent(event_message, event_error_code);
                    app.addNewEventLogEvents(new_event);

                    % Apply text to TLE text edit field
                    app.TLETextTextArea.Value = event_message;
    
                    % Disable add TLE button
                    app.AddSatelliteFromTLEButton.Enable = false;
    
                    % Exit the function
                    return
                end

                % Apply text to TLE text edit field
                app.TLETextTextArea.Value = raw_text;

                % Enable add TLE button
                app.AddSatelliteFromTLEButton.Enable = true;

                % If successful, create a message for the console log
                event_message = sprintf('Successfully read %d lines from %s.', total_num_lines, TLE_source);
                new_event = ConsoleEvent(event_message);
                app.addNewEventLogEvents(new_event);

            catch e

                % If unsuccessful, create a message for the console log
                event_message = sprintf('Could not read file %s, no TLE data loaded.', file);
                event_error_code = StatusCode.Error;
                new_event = ConsoleEvent(event_message, event_error_code, e);
                app.addNewEventLogEvents(new_event);

                % Apply text to TLE text edit field
                app.TLETextTextArea.Value = event_message;

                % Disable add TLE button
                app.AddSatelliteFromTLEButton.Enable = false;

                % Exit the function
                return

            end


            % % % Load TLE from file via TLE handler
            % % ignore_checksum = app.SatelliteInfoIgnoreChecksumsCheckBox.Value;
            % 
            % % Get values to search for within the TLE file. These are
            % % given as a comma separated list.
            % search_TLE_names = app.TLEFromFileSearchTextArea.Value;
            % 
            % % If search is empty, load all of them
            % if isempty(search_TLE_names)
            %     TLEs_to_load = '';
            %     app.tleHandler.addTLEFromFile(full_file_path, ignore_checksum, TLEs_to_load);
            % else
            %     search_TLE_names = strtrim(split(cell2mat(search_TLE_names), ','));
            %     for i = 1:numel(search_TLE_names)
            %         TLEs_to_load = search_TLE_names{i};
            %             app.tleHandler.addTLEFromFile(full_file_path, ignore_checksum, TLEs_to_load);
            %     end
            % end
            % 
            % % Populate the TLE tab wiht the first TLE
            % TLE_list = app.tleHandler.TLEHandles;
            % 
            % if ~isempty(TLE_list)
            %     TLE_to_show = TLE_list(1);
            %     populateTLE(app, TLE_to_show);
            %     populateLoadedSatellitesTable(app)
            % end

            % % Broadcast to the tab state manager that the TLE info has been
            % % changed.
            % % TODO: call other tabs' reset functions to clear our old info
            % % TODO: create a property variable to handle event names
            % app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
            % % TODO: more robust version of this
            % app.SGP4PropagatorPropagateButton.Enable = false;

            % % Check whether TLE(s) are valid
            % % TODO: support multiple TLEs
            % %                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
            % %                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
            % if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)
            % 
            %     % If valid, broadcast state event
            %     app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
            %     % TODO: more robust version of this
            %     app.SGP4PropagatorPropagateButton.Enable = true;
            % 
            % end
            % 
            % % TODO: more generic event listener in TabStateHandler?
            % app.update_all_tab_state_indicators();

        end

        % Callback function
        function LoadedSatellitesDeleteButtonPushed(app, event)
            % FIXED, NOW IMPLEMENT THIS FUNCTION INTO THE TLE FACTORY/TLE
            % HANDLER

            % Allow for multiple deletions at the same time
            selected_TLEs = app.LoadedSatellitesTable.Selection;
            num_selected_TLEs = length(selected_TLEs);

            j = 1;

            for i = 1:num_selected_TLEs

                % Get data in table
                current_table_data = app.LoadedSatellitesTable.Data;

                % If there's multiple deletions, decrease index by one
                if isequal(i, 1)
                    TLE_index = selected_TLEs(i);
                else
                    TLE_index = selected_TLEs(i) - j;
                    j = j + 1;
                end

                % Get names column
                names_column = 1;
                
                % Find name in table
                TLE_name = current_table_data{TLE_index, names_column};

                % Find TLE object
                TLE = app.tleHandler.getTLEByName(TLE_name, false);
                
                % Remove TLE from TLE list
                app.tleHandler.removeTLE(TLE, TLE_index);

                % Update tab
                TLE_list = app.tleHandler.TLEHandles;

                if ~isempty(TLE_list)
                    first_TLE = TLE_list(1);
                    populateTLEValues(app, 1);%first_TLE);
                else
                    populateTLEValues(app, 0);
                end

                % Update table
                updateLoadedSatellitesTable(app);

                % Broadcast to the tab state manager that the TLE info has been
                % changed. 
                % TODO: call other tabs' reset functions to clear our old info
                % TODO: create a property variable to handle event names
                app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
                % TODO: more robust version of this
                app.SGP4PropagatorPropagateButton.Enable = false;

                % Check whether TLE(s) are valid
                % TODO: support multiple TLEs
%                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
%                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
                if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)

                    % If valid, broadcast state event
                    app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
                    % TODO: more robust version of this
                    app.SGP4PropagatorPropagateButton.Enable = true;

                end

                % TODO: more generic event listener in TabStateHandler?
                app.update_all_tab_state_indicators();
                
            end
        end

        % Button pushed function: TLEFromURLLoadButton
        function TLEFromURLLoadButtonPushed(app, event)
                       
            % Load data from inputted URL
            TLE_source = app.TLEFromURLEditField.Value;  % default use https://www.celestrak.com/NORAD/elements/active.txt

            % Check if the user has entered a URL
            if isempty(TLE_source)

                % If no URL is given, create a message for the console log
                event_message = 'No URL given, no TLE data loaded.';
                new_event = ConsoleEvent(event_message);
                app.addNewEventLogEvents(new_event);

                % Exit the function
                return
            
            else

                % If URL is given, create a message for the console log
                event_message = sprintf('User selected URL %s.', TLE_source);
                new_event = ConsoleEvent(event_message);
                app.addNewEventLogEvents(new_event);

            end


            % Load text from URL
            try
                
                % Load raw lines from file
                raw_text = webread(TLE_source);
                raw_text = splitlines(raw_text);

                % Trim text of leading or trailing whitespace
                raw_text = strip(raw_text);

                % Add objects, allows for multiple TLEs to be added. They must
                % be separated by commas
                search_terms = app.TLEFromURLSearchTextArea.Value;
                exact_match_required = app.TLEFromURLExactMatchCheckBox.Value;

                % If search terms are present, find the relevant TLE data
                % from the raw text
                if ~isempty(search_terms{:})
                    

                    % Split lines 
                    if isequal(1, numel(search_terms))
                        search_terms = strip(split(search_terms, ','));
                    end

                    [raw_text, function_events] = app.tleHandler.findTLE(raw_text, search_terms, exact_match_required);
                    app.addNewEventLogEvents(function_events);

                end

                total_num_lines = length(raw_text);

                if total_num_lines == 0
                    event_message = sprintf('Unable to find TLE data in %s.', TLE_source);
                    event_error_code = StatusCode.Warning;
                    new_event = ConsoleEvent(event_message, event_error_code);
                    app.addNewEventLogEvents(new_event);

                    % Apply text to TLE text edit field
                    app.TLETextTextArea.Value = event_message;
    
                    % Disable add TLE button
                    app.AddSatelliteFromTLEButton.Enable = false;
    
                    % Exit the function
                    return
                end

                % Apply text to TLE text edit field
                app.TLETextTextArea.Value = raw_text;

                % Enable add TLE button
                app.AddSatelliteFromTLEButton.Enable = true;

                % If successful, create a message for the console log
                event_message = sprintf('Successfully read %d lines from %s.', total_num_lines, TLE_source);
                new_event = ConsoleEvent(event_message);
                app.addNewEventLogEvents(new_event);

            catch e

                % If unsuccessful, create a message for the console log
                event_message = sprintf('Could not conntect to and/or read URL %s, no TLE data loaded.', TLE_source);
                event_error_code = StatusCode.Error;
                new_event = ConsoleEvent(event_message, event_error_code, e);
                app.addNewEventLogEvents(new_event);

                % Apply text to TLE text edit field
                app.TLETextTextArea.Value = event_message;

                % Disable add TLE button
                app.AddSatelliteFromTLEButton.Enable = false;

                % % Exit the function
                % return

            end


            % % Read off ignore checksum value
            % ignore_checksum = app.SatelliteInfoIgnoreChecksumsCheckBox.Value;
            % 
            % % Add objects, allows for multiple TLEs to be added. They must
            % % be separated by commas
            % sat_num_or_name = app.TLEFromURLSearchTextArea.Value;

            % % If search is empty, error
            % if isempty(sat_num_or_name)
            %     % new_gui_event = ConsoleEvent("A satellite number or name must be given.")
            %     app.GUIEventManager.addEvent(new_gui_event)
            % else
            % 
            %     % Split lines 
            %     if isequal(1, numel(sat_num_or_name))
            %         sat_num_or_name = strtrim(split(cell2mat(sat_num_or_name), ','));
            %     end
            % 
            %     for i = 1:numel(sat_num_or_name)
            %         app.tleHandler.addTLEFromURL(TLE_source, ignore_checksum, sat_num_or_name{i});
            %     end
            % end

%             % Populate the TLE tab wiht the first TLE and load the table
%             TLE_list = app.tleHandler.TLEHandles;
% 
%             if ~isempty(TLE_list)
%                 TLE_to_show = TLE_list(1);
%                 populateTLE(app, TLE_to_show);
%                 populateLoadedSatellitesTable(app)
%             end
% 
% 
%             % Broadcast to the tab state manager that the TLE info has been
%             % changed. 
%             % TODO: call other tabs' reset functions to clear our old info
%             % TODO: create a property variable to handle event names
%             app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
%             % TODO: more robust version of this
%             app.SGP4PropagatorPropagateButton.Enable = false;
% 
%             % Check whether TLE(s) are valid
%             % TODO: support multiple TLEs
% %                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
% %                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
%             if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)
% 
%                 % If valid, broadcast state event
%                 app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
%                 % TODO: more robust version of this
%                 app.SGP4PropagatorPropagateButton.Enable = true;
% 
%             end
% 
%             % TODO: more generic event listener in TabStateHandler?
%             app.update_all_tab_state_indicators();
            
        end

        % Cell selection callback: LoadedSatellitesTable
        function LoadedSatellitesTableCellSelection(app, event)
            
            % Get the index of the selected events
            index = event.Indices;

            % Extract the row index
            if ~isempty(index)
                row_selected = index(1);
                % names_column = 1;

                % Get TLE from table
                TLE_index_to_show = app.LoadedSatellitesTable.Data.ID{row_selected};

                % % Find TLE in TLE list
                % TLE_to_show = app.tleHandler.TLEHandles(TLE_index_to_show);

                % If name and TLE match,
                % if strcmp(sat_name, TLE_index_to_show)
                    populateTLEValues(app, TLE_index_to_show)
                % else
                %     warning('CATASTROPHIC ERROR, GO TO A BUNKER RIGHT NOW')
                % end
            end
        end

        % Value changed function: SatelliteInfoNameEditField
        function SatelliteInfoNameEditFieldValueChanged(app, event)
            % Get new name from GUI
            new_name_input = app.SatelliteInfoNameEditField.Value;

            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setSatelliteName(new_name_input, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Callback function
        function LoadedSatellitesLoadButtonPushed(app, event)
            app.TLEFromFileOpenButton.ButtonPushedFcn(app, event);
        end

        % Callback function
        function LoadedSatellitesExportButtonPushed(app, event)
            % TODO: Export the lines instead of the data in the table


            % Get TLE list
            TLE_list = app.tleHandler.TLEHandles;

            % Name of file is the name of the first satellite
            if ~isempty(TLE_list)
                default_filename = TLE_list(1).SatelliteName;
            else
                default_filename = 'TLE file';
            end

            % Prompt user for file
            window_title = 'Save as';
            [file, path] = uiputfile( ...
                {'*.txt', 'TXT Files (*.txt)'; '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                window_title, default_filename);

            % Check if user selected cancel
            if isequal(file,0)
                disp('User selected Cancel, did not save.');
            else

                % Successfully selected a file
                table_data = string();

                k = 1;
                for i = 1:numel(TLE_list)
                    table_data(k, 1) = TLE_list(i).SatelliteName;
                    table_data(k + 1, 1) = TLE_list(i).TLELine1;
                    table_data(k + 2, 1) = TLE_list(i).TLELine2;
                    k = k + 3;
                end

                table_dest = fullfile(path,file);
                fprintf('User selected %s to save file at.\n', table_dest);

                % Write file
                % table_data = app.LoadedSatellitesTable.Data;
                writematrix(table_data, table_dest)
            end
        end

        % Callback function
        function SatellitePresetAddButtonPushed(app, event)
            option = app.SatellitePresetDropDown.Value;

            if isequal(option, 'Sun-synchronous')
                % Assign name
                sat_name = 'Sun-sync Orbit Preset';

                % Assign Semi Major axis
                semi_major_axis = 700;

                % Assign Eccentricity
                eccentricity = 0.002;

                % Assign Inclination
                inclination = 98;

                % Assign RAAN
                RAAN = 180;

                % Assign Argument of Periapsis
                arg_of_periapsis = 180;

                % Assign Mean Anomaly
                mean_motion = 10;

                % Create TLE
                app.tleHandler.addTLEfromKeplerianElements(sat_name, semi_major_axis, ...
                     eccentricity, inclination, RAAN, arg_of_periapsis, mean_motion);

                % Update tab
                sun_sync_TLE = app.tleHandler.getTLEByName(sat_name, false);
                
                populateTLEValues(app, sun_sync_TLE);
                
                % Update table
                updateLoadedSatellitesTable(app);

            elseif isequal(option, 'Geostationary')
                % Assign name
                sat_name = 'Goestationary Orbit Preset';

                % Assign Semi Major axis
                semi_major_axis = 40000;

                % Assign Eccentricity
                eccentricity = 0.001;

                % Assign Inclination
                inclination = 0;

                % Assign RAAN
                RAAN = 0;

                % Assign Argument of Periapsis
                arg_of_periapsis = 0;

                % Assign Mean Anomaly
                mean_motion = 5;

                % Create TLE
                app.tleHandler.addTLEfromKeplerianElements(sat_name, semi_major_axis, ...
                     eccentricity, inclination, RAAN, arg_of_periapsis, mean_motion);

                % Update tab
                sun_sync_TLE = app.tleHandler.getTLEByName(sat_name, false);
                
                populateTLEValues(app, sun_sync_TLE);
                
                % Update table
                updateLoadedSatellitesTable(app);

            end
        end

        % Callback function
        function SatelliteInfoAutogenerateChecksumsCheckBoxValueChanged(app, event)
            % value = app.SatelliteInfoAutogenerateChecksumsCheckBox.Value;
            % TODO: Implement this
        end

        % Value changed function: SGP4PropagatorStartTimeEditField
        function SGP4PropagatorStartTimeEditFieldValueChanged(app, event)
            value = app.SGP4PropagatorStartTimeEditField.Value;
            
        end

        % Button pushed function: AddSatelliteFromTLEButton
        function AddSatelliteFromTLEButtonPushed(app, event)
            
            % Get raw text from TLE text field
            unparsed_TLE_text = app.TLETextTextArea.Value;

            % Try to parse the raw text
            function_events = app.tleHandler.addTLEsFromText(unparsed_TLE_text);

            % Add function events which were issued
            app.addNewEventLogEvents(function_events);

            % Update satellites table
            app.updateLoadedSatellitesTable();


        end

        % Value changed function: TLETextTextArea
        function TLETextTextAreaValueChanged(app, event)
            
            % Do some slight pre-processing on text
            value = strip(cell2str(app.TLETextTextArea.Value));
            
            if ~isempty(value)
                app.AddSatelliteFromTLEButton.Enable = true;
            else
                app.AddSatelliteFromTLEButton.Enable = false;
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clearConsoleLog();
        end

        % Value changed function: VerboseCheckBox
        function VerboseCheckBoxValueChanged(app, event)
            value = app.VerboseCheckBox.Value;
            app.eventLogHandler.Verbose = value;
            app.updateEventLogText();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            disp('test')
        end

        % Button pushed function: AddSatelliteFromKeplerianElementsButton
        function AddSatelliteFromKeplerianElementsButtonPushed(app, event)
            % Get variables from input fields
            satellite_name = app.AddSatelliteFromKeplerianElementsNameEditField.Value;
            % TODO: replace this with Sebastian's custom component
            epoch_date = app.AddSatelliteFromKeplerianElementsEpochDate.Value;
            % epoch_time_str = app.AddSatelliteFromKeplerianElementsEpochTime.Value;  % for now I just won't parse this
            [epoch_year, epoch_day] = datetimeToYearAndDOY(epoch_date);

            % Keplerian elements
            semimajor_axis_km = app.AddSatelliteFromKeplerianElementsSemimajorAxis.Value;
            semimajor_axis_m = semimajor_axis_km*1000;
            eccentricity = app.AddSatelliteFromKeplerianElementsEccentricity.Value;
            inclination = app.AddSatelliteFromKeplerianElementsInclination.Value;
            raan = app.AddSatelliteFromKeplerianElementsRAAN.Value;
            arg_of_periapsis = app.AddSatelliteFromKeplerianElementsArgofPeriapsis.Value;
            true_anomaly = app.AddSatelliteFromKeplerianElementsTrueAnomaly.Value;

            % Try to parse inputs
            function_events = app.tleHandler.addTLEsFromKeplerianElements( ...
                satellite_name, epoch_year, epoch_day, semimajor_axis_m, ...
                eccentricity, inclination, raan, arg_of_periapsis, true_anomaly);

            % Add function events which were issued
            app.addNewEventLogEvents(function_events);

            % Update satellites table
            app.updateLoadedSatellitesTable();
            
        end

        % Button pushed function: AddSatelliteFromSGP4ElementsButton
        function AddSatelliteFromSGP4ElementsButtonPushed(app, event)
            % Get variables from input fields
            satellite_name = app.AddSatelliteFromSGP4ElementsNameEditField.Value;
            % TODO: replace this with Sebastian's custom component
            epoch_date = app.AddSatelliteFromSGP4ElementsEpochDate.Value;
            % epoch_time_str = app.AddSatelliteFromSGP4ElementsEpochTime.Value;  % for now I just won't parse this
            [epoch_year, epoch_day] = datetimeToYearAndDOY(epoch_date);

            % SGP4 elements
            mean_motion = app.AddSatelliteFromSGP4ElementsMeanMotion.Value;
            eccentricity = app.AddSatelliteFromSGP4ElementsEccentricity.Value;
            inclination = app.AddSatelliteFromSGP4ElementsInclination.Value;
            raan = app.AddSatelliteFromSGP4ElementsRAAN.Value;
            arg_of_periapsis = app.AddSatelliteFromSGP4ElementsArgofPeriapsis.Value;
            mean_anomaly = app.AddSatelliteFromSGP4ElementsMeanAnomaly.Value;
            b_star = app.AddSatelliteFromSGP4ElementsBStar.Value;

            % Try to parse inputs
            function_events = app.tleHandler.addTLEsFromSGP4Elements( ...
                satellite_name, epoch_year, epoch_day, mean_motion, ...
                eccentricity, inclination, raan, arg_of_periapsis, ...
                mean_anomaly, b_star);

            % Add function events which were issued
            app.addNewEventLogEvents(function_events);

            % Update satellites table
            app.updateLoadedSatellitesTable();
        
        end

        % Value changed function: 
        % AddSatelliteFromKeplerianElementsEpochDate
        function AddSatelliteFromKeplerianElementsEpochDateValueChanged(app, event)
            value = app.AddSatelliteFromKeplerianElementsEpochDate.Value;
            % TODO: check validity etc
            if ~isempty(value)
                app.AddSatelliteFromKeplerianElementsButton.Enable = true;
            end
        end

        % Value changed function: AddSatelliteFromSGP4ElementsEpochDate
        function AddSatelliteFromSGP4ElementsEpochDateValueChanged(app, event)
            value = app.AddSatelliteFromSGP4ElementsEpochDate.Value;
            % TODO: check validity etc
            if ~isempty(value)
                app.AddSatelliteFromSGP4ElementsButton.Enable = true;
            end
        end

        % Value changed function: TLEDataLine1CatalogNumEditField
        function TLEDataLine1CatalogNumEditFieldValueChanged(app, event)
            value = app.TLEDataLine1CatalogNumEditField.Value;

            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setCatalogNum(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end

        end

        % Value changed function: TLEDataLine1ClassificationEditField
        function TLEDataLine1ClassificationEditFieldValueChanged(app, event)
            value = app.TLEDataLine1ClassificationEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setClassification(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end

        end

        % Value changed function: SatelliteInfoEpochDatePicker
        function SatelliteInfoEpochDatePickerValueChanged(app, event)

            value = app.SatelliteInfoEpochDatePicker.Value;

            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setEpoch(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: 
        % KeplerianElementsTabSemimajorAxisEditField
        function KeplerianElementsTabSemimajorAxisEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabSemimajorAxisEditField.Value;

            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setSemimajorAxis(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
            
        end

        % Value changed function: KeplerianElementsTabInclinationEditField
        function KeplerianElementsTabInclinationEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabInclinationEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setInclination(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: KeplerianElementsTabRAANEditField
        function KeplerianElementsTabRAANEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabRAANEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setRAAN(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: KeplerianElementsTabEccentricityEditField
        function KeplerianElementsTabEccentricityEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabEccentricityEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                % Extra argument here is to denote that this is being set
                % from the Keplerian elements tab, meaning true anomaly
                % (and NOT mean anomaly) is being held constant, so mean
                % anomaly must be recalculated
                function_events = app.tleHandler.setEpoch(value, selected_TLE_index, false);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: 
        % KeplerianElementsTabArgumentOfPerigeeEditField
        function KeplerianElementsTabArgumentOfPerigeeEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabArgumentOfPerigeeEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setArgOfPeriapsis(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: KeplerianElementsTabTrueAnomalyEditField
        function KeplerianElementsTabTrueAnomalyEditFieldValueChanged(app, event)
            value = app.KeplerianElementsTabTrueAnomalyEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setTrueAnomaly(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1LaunchYrEditField
        function TLEDataLine1LaunchYrEditFieldValueChanged(app, event)
            value = app.TLEDataLine1LaunchYrEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setLaunchYear(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1LaunchNumEditField
        function TLEDataLine1LaunchNumEditFieldValueChanged(app, event)
            value = app.TLEDataLine1LaunchNumEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setLaunchNumber(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1LaunchPieceEditField
        function TLEDataLine1LaunchPieceEditFieldValueChanged(app, event)
            value = app.TLEDataLine1LaunchPieceEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setLaunchPiece(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1EpochYearEditField
        function TLEDataLine1EpochYearEditFieldValueChanged(app, event)
            value = app.TLEDataLine1EpochYearEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setEpochYear(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1EpochDayEditField
        function TLEDataLine1EpochDayEditFieldValueChanged(app, event)
            value = app.TLEDataLine1EpochDayEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setEpochDay(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: 
        % TLEDataLine1FirstDerivofMeanMotionEditField
        function TLEDataLine1FirstDerivofMeanMotionEditFieldValueChanged(app, event)
            value = app.TLEDataLine1FirstDerivofMeanMotionEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setFirstDerivofMeanMotion(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: 
        % TLEDataLine1SecondDerivofMeanMotionEditField
        function TLEDataLine1SecondDerivofMeanMotionEditFieldValueChanged(app, event)
            value = app.TLEDataLine1SecondDerivofMeanMotionEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setSecondDerivofMeanMotion(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1BStarEditField
        function TLEDataLine1BStarEditFieldValueChanged(app, event)
            value = app.TLEDataLine1BStarEditField.Value;
            
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setBStar(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1EphemerisTypeEditField
        function TLEDataLine1EphemerisTypeEditFieldValueChanged(app, event)
            value = app.TLEDataLine1EphemerisTypeEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setEphemerisType(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1ElementSetNumberEditField
        function TLEDataLine1ElementSetNumberEditFieldValueChanged(app, event)
            value = app.TLEDataLine1ElementSetNumberEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setElementSetNum(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine1ChecksumEditField
        function TLEDataLine1ChecksumEditFieldValueChanged(app, event)
            value = app.TLEDataLine1ChecksumEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                % Extra argument is to indicate line 1
                function_events = app.tleHandler.setChecksum(value, 1, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2CatalogNumEditField
        function TLEDataLine2CatalogNumEditFieldValueChanged(app, event)
            value = app.TLEDataLine2CatalogNumEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setCatalogNum(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2InclinationEditField
        function TLEDataLine2InclinationEditFieldValueChanged(app, event)
            value = app.TLEDataLine2InclinationEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setInclination(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2RAANEditField
        function TLEDataLine2RAANEditFieldValueChanged(app, event)
            value = app.TLEDataLine2RAANEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setRAAN(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2EccentricityEditField
        function TLEDataLine2EccentricityEditFieldValueChanged(app, event)
            value = app.TLEDataLine2EccentricityEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                % Extra argument is to denote that this is being changed
                % from the SGP4 elements, meaning that the mean anomaly is
                % going to be held constant and true anomaly will be
                % recalculated.
                function_events = app.tleHandler.setEccentricity(value, selected_TLE_index, true);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2ArgofPerigeeEditField
        function TLEDataLine2ArgofPerigeeEditFieldValueChanged(app, event)
            value = app.TLEDataLine2ArgofPerigeeEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setArgOfPeriapsis(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2MeanAnomalyEditField
        function TLEDataLine2MeanAnomalyEditFieldValueChanged(app, event)
            value = app.TLEDataLine2MeanAnomalyEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setMeanAnomaly(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2MeanMotionEditField
        function TLEDataLine2MeanMotionEditFieldValueChanged(app, event)
            value = app.TLEDataLine2MeanMotionEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setMeanMotion(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2RevNoatEpochEditField
        function TLEDataLine2RevNoatEpochEditFieldValueChanged(app, event)
            value = app.TLEDataLine2RevNoatEpochEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                function_events = app.tleHandler.setRevNum(value, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Value changed function: TLEDataLine2ChecksumEditField
        function TLEDataLine2ChecksumEditFieldValueChanged(app, event)
            value = app.TLEDataLine2ChecksumEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLE_index = app.LoadedSatellitesTable.Selection(1);

            % Change the TLE that's selected
            if ~isempty(selected_TLE_index)

                % Change the value via TLE handler
                % Extra argument is to denote checksum for line 2
                function_events = app.tleHandler.setChecksum(value, 2, selected_TLE_index);

                % Update the tab
                populateTLEValues(app, selected_TLE_index);

                % Update the table
                updateLoadedSatellitesTable(app);

                % Add the function events
                app.addNewEventLogEvents(function_events);

            end
        end

        % Selection changed function: LoadedSatellitesTable
        function LoadedSatellitesTableSelectionChanged(app, event)
            selection = app.LoadedSatellitesTable.Selection;

            if ~isempty(selection)
                TLE_index = selection(1);
    
                function_event = ConsoleEvent(sprintf('Selection changed to satellite ID %d.', ...
                    TLE_index));
                
                app.tleHandler.LastSelectedTLEIndex = TLE_index;
            else
                TLE_index = app.tleHandler.LastSelectedTLEIndex;
                app.LoadedSatellitesTable.Selection = TLE_index;
                function_event = ConsoleEvent(sprintf('No satellite selected. Setting to last selection ID %d.', ...
                    TLE_index));
            end

            app.addNewEventLogEvents(function_event);
        end

        % Value changed function: AutogenerateChecksumsCheckBox
        function AutogenerateChecksumsCheckBoxValueChanged(app, event)
            value = app.AutogenerateChecksumsCheckBox.Value;

            app.TLEDataLine1ChecksumEditField.Editable = value;
            app.TLEDataLine2ChecksumEditField.Editable = value;

            function_events = app.tleHandler.setAutoGenerateChecksumsFlag(value);

            app.addNewEventLogEvents(function_events);

            app.updateLoadedSatellitesTable();
            selected_index = app.tleHandler.LastSelectedTLEIndex;
            app.populateTLEValues(selected_index);

        end

        % Value changed function: IgnoreChecksumsCheckBox
        function IgnoreChecksumsCheckBoxValueChanged(app, event)
            value = app.IgnoreChecksumsCheckBox.Value;

            function_events = app.tleHandler.setIgnoreChecksumsFlag(value);

            app.addNewEventLogEvents(function_events);

            app.updateLoadedSatellitesTable();
            selected_index = app.tleHandler.LastSelectedTLEIndex;
            app.populateTLEValues(selected_index);
            
        end

        % Value changed function: SGP4PropagatorStartDateDatePicker
        function SGP4PropagatorStartDateDatePickerValueChanged(app, event)
            start_date_value = app.SGP4PropagatorStartDateDatePicker.Value;
            end_date_value = app.SGP4PropagatorEndDateDatePicker.Value;
            
            if ~isnat(start_date_value) && ~isnat(end_date_value)
                app.SGP4PropagatorPropagateButton.Enable = true;
            else
                app.SGP4PropagatorPropagateButton.Enable = false;
            end
        end

        % Value changed function: SGP4PropagatorEndDateDatePicker
        function SGP4PropagatorEndDateDatePickerValueChanged(app, event)
            start_date_value = app.SGP4PropagatorStartDateDatePicker.Value;
            end_date_value = app.SGP4PropagatorEndDateDatePicker.Value;
            
            if ~isnat(start_date_value) && ~isnat(end_date_value)
                app.SGP4PropagatorPropagateButton.Enable = true;
            else
                app.SGP4PropagatorPropagateButton.Enable = false;
            end
        end

        % Value changed function: SGP4TableSatellitesDisplayDropDown
        function SGP4TableSatellitesDisplayDropDownValueChanged(app, event)
            value = app.SGP4TableSatellitesDisplayDropDown.Value;
            app.updatePropagationTable();
        end

        % Value changed function: SGP4TableTimestampFormatDropDown
        function SGP4TableTimestampFormatDropDownValueChanged(app, event)
            value = app.SGP4TableTimestampFormatDropDown.Value;
            app.updatePropagationTable();
        end

        % Value changed function: SGP4TableCoordinatesFormatDropDown
        function SGP4TableCoordinatesFormatDropDownValueChanged(app, event)
            value = app.SGP4TableCoordinatesFormatDropDown.Value;
            app.updatePropagationTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MasterGridLayout = uigridlayout(app.UIFigure);
            app.MasterTabGroup = uitabgroup(app.MasterGridLayout);
            app.SatelliteTab = uitab(app.MasterTabGroup);
            app.SatelliteTab.Title = 'Satellite';
            app.SatelliteTabLayout = uigridlayout(app.SatelliteTab);
            app.SatelliteInfoPanel = uipanel(app.SatelliteTabLayout);
            app.SatelliteInfoPanel.Title = 'Satellite Info';
            app.SatelliteInfoPanelLayout = uigridlayout(app.SatelliteInfoPanel);
            app.SatelliteInfoTabGroup = uitabgroup(app.SatelliteInfoPanelLayout);
            app.TLEDataTab = uitab(app.SatelliteInfoTabGroup);
            app.TLEDataTab.Title = 'TLE Data';
            app.TLEDataTabLayout = uigridlayout(app.TLEDataTab);
            app.TLEDataLine2Panel = uipanel(app.TLEDataTabLayout);
            app.TLEDataLine2Panel.Title = 'TLE Line 2';
            app.TLELine2Layout = uigridlayout(app.TLEDataLine2Panel);
            app.InclinationdegEditField_3Label_2 = uilabel(app.TLELine2Layout);
            app.InclinationdegEditField_3Label_2.Text = 'Inclination (deg)';
            app.RAANdegEditField_3Label_2 = uilabel(app.TLELine2Layout);
            app.RAANdegEditField_3Label_2.Text = 'RAAN (deg)';
            app.TLEDataLine2ChecksumEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2ChecksumEditField.Limits = [0 9];
            app.TLEDataLine2ChecksumEditField.RoundFractionalValues = 'on';
            app.TLEDataLine2ChecksumEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2ChecksumEditFieldValueChanged, true);
            app.TLEDataLine2ChecksumEditField.Editable = 'off';
            app.TLEDataLine2RevNoatEpochEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2RevNoatEpochEditField.Limits = [0 99999];
            app.TLEDataLine2RevNoatEpochEditField.RoundFractionalValues = 'on';
            app.TLEDataLine2RevNoatEpochEditField.ValueDisplayFormat = '% 5.f';
            app.TLEDataLine2RevNoatEpochEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2RevNoatEpochEditFieldValueChanged, true);
            app.TLEDataLine2RevNoatEpochEditField.Editable = 'off';
            app.TLEDataLine2MeanMotionEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2MeanMotionEditField.LowerLimitInclusive = 'off';
            app.TLEDataLine2MeanMotionEditField.UpperLimitInclusive = 'off';
            app.TLEDataLine2MeanMotionEditField.Limits = [6.5 100];
            app.TLEDataLine2MeanMotionEditField.ValueDisplayFormat = '%11.8f';
            app.TLEDataLine2MeanMotionEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2MeanMotionEditFieldValueChanged, true);
            app.TLEDataLine2MeanMotionEditField.Editable = 'off';
            app.TLEDataLine2MeanMotionEditField.Value = 7.5;
            app.EccentricityEditField_3Label_2 = uilabel(app.TLELine2Layout);
            app.EccentricityEditField_3Label_2.Text = 'Eccentricity';
            app.ArgofPerigeedegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.ArgofPerigeedegEditFieldLabel.Text = 'Arg of Perigee (deg)';
            app.MeanAnomalydegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.MeanAnomalydegEditFieldLabel.Text = 'Mean Anomaly (deg)';
            app.TLEDataLine2MeanAnomalyEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2MeanAnomalyEditField.UpperLimitInclusive = 'off';
            app.TLEDataLine2MeanAnomalyEditField.Limits = [0 360];
            app.TLEDataLine2MeanAnomalyEditField.ValueDisplayFormat = '%8.4f';
            app.TLEDataLine2MeanAnomalyEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2MeanAnomalyEditFieldValueChanged, true);
            app.TLEDataLine2MeanAnomalyEditField.Editable = 'off';
            app.TLEDataLine2ArgofPerigeeEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2ArgofPerigeeEditField.UpperLimitInclusive = 'off';
            app.TLEDataLine2ArgofPerigeeEditField.Limits = [0 360];
            app.TLEDataLine2ArgofPerigeeEditField.ValueDisplayFormat = '%8.4f';
            app.TLEDataLine2ArgofPerigeeEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2ArgofPerigeeEditFieldValueChanged, true);
            app.TLEDataLine2ArgofPerigeeEditField.Editable = 'off';
            app.TLEDataLine2EccentricityEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2EccentricityEditField.Limits = [0 1];
            app.TLEDataLine2EccentricityEditField.ValueDisplayFormat = '%.7f';
            app.TLEDataLine2EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2EccentricityEditFieldValueChanged, true);
            app.TLEDataLine2EccentricityEditField.Editable = 'off';
            app.TLEDataLine2RAANEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2RAANEditField.UpperLimitInclusive = 'off';
            app.TLEDataLine2RAANEditField.Limits = [0 360];
            app.TLEDataLine2RAANEditField.ValueDisplayFormat = '%8.4f';
            app.TLEDataLine2RAANEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2RAANEditFieldValueChanged, true);
            app.TLEDataLine2RAANEditField.Editable = 'off';
            app.MeanMotionrevdayEditFieldLabel = uilabel(app.TLELine2Layout);
            app.MeanMotionrevdayEditFieldLabel.Text = 'Mean Motion (rev/day)';
            app.RevNoatEpochEditFieldLabel = uilabel(app.TLELine2Layout);
            app.RevNoatEpochEditFieldLabel.Text = 'Rev. No. at Epoch';
            app.Checksummod10EditField_2Label = uilabel(app.TLELine2Layout);
            app.Checksummod10EditField_2Label.Text = 'Checksum (mod 10)';
            app.TLEDataLine2InclinationEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2InclinationEditField.Limits = [0 180];
            app.TLEDataLine2InclinationEditField.ValueDisplayFormat = '%8.4f';
            app.TLEDataLine2InclinationEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2InclinationEditFieldValueChanged, true);
            app.TLEDataLine2InclinationEditField.Editable = 'off';
            app.SatelliteCatalogNumberEditField_3Label_2 = uilabel(app.TLELine2Layout);
            app.SatelliteCatalogNumberEditField_3Label_2.Text = 'Satellite Catalog Number';
            app.TLEDataLine2CatalogNumEditField = uieditfield(app.TLELine2Layout, 'numeric');
            app.TLEDataLine2CatalogNumEditField.Limits = [0 99999];
            app.TLEDataLine2CatalogNumEditField.RoundFractionalValues = 'on';
            app.TLEDataLine2CatalogNumEditField.ValueDisplayFormat = '%5d';
            app.TLEDataLine2CatalogNumEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine2CatalogNumEditFieldValueChanged, true);
            app.TLEDataLine2CatalogNumEditField.Editable = 'off';
            app.TLEDataLine1Panel = uipanel(app.TLEDataTabLayout);
            app.TLEDataLine1Panel.Title = 'TLE Line 1';
            app.TLEDataLine1PanelLayout = uigridlayout(app.TLEDataLine1Panel);
            app.ClassificationEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.ClassificationEditFieldLabel.Text = 'Classification';
            app.IntlDesignatorpieceEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.IntlDesignatorpieceEditFieldLabel.Text = 'Intl Designator (piece)';
            app.EphemerisTypeEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.EphemerisTypeEditFieldLabel.Text = 'Ephemeris Type';
            app.TLEDataLine1ChecksumEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1ChecksumEditField.Limits = [0 9];
            app.TLEDataLine1ChecksumEditField.RoundFractionalValues = 'on';
            app.TLEDataLine1ChecksumEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1ChecksumEditFieldValueChanged, true);
            app.TLEDataLine1ChecksumEditField.Editable = 'off';
            app.TLEDataLine1ElementSetNumberEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1ElementSetNumberEditField.Limits = [0 9999];
            app.TLEDataLine1ElementSetNumberEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1ElementSetNumberEditFieldValueChanged, true);
            app.TLEDataLine1ElementSetNumberEditField.Editable = 'off';
            app.TLEDataLine1EphemerisTypeEditField = uieditfield(app.TLEDataLine1PanelLayout, 'text');
            app.TLEDataLine1EphemerisTypeEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1EphemerisTypeEditFieldValueChanged, true);
            app.TLEDataLine1EphemerisTypeEditField.Editable = 'off';
            app.TLEDataLine1BStarEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1BStarEditField.Limits = [-99999000000000 99999000000000];
            app.TLEDataLine1BStarEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1BStarEditFieldValueChanged, true);
            app.TLEDataLine1BStarEditField.Editable = 'off';
            app.TLEDataLine1SecondDerivofMeanMotionEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1SecondDerivofMeanMotionEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1SecondDerivofMeanMotionEditFieldValueChanged, true);
            app.TLEDataLine1SecondDerivofMeanMotionEditField.Editable = 'off';
            app.TLEDataLine1FirstDerivofMeanMotionEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1FirstDerivofMeanMotionEditField.Limits = [-1 1];
            app.TLEDataLine1FirstDerivofMeanMotionEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1FirstDerivofMeanMotionEditFieldValueChanged, true);
            app.TLEDataLine1FirstDerivofMeanMotionEditField.Editable = 'off';
            app.SatelliteCatalogNumberEditField_3Label = uilabel(app.TLEDataLine1PanelLayout);
            app.SatelliteCatalogNumberEditField_3Label.Text = 'Satellite Catalog Number';
            app.IntlDesignatorlaunchyrEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.IntlDesignatorlaunchyrEditFieldLabel.Text = 'Intl. Designator (launch yr)';
            app.IntlDesignatorlaunchnoEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.IntlDesignatorlaunchnoEditFieldLabel.Text = 'Intl. Designator (launch no.)';
            app.EpochYearEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.EpochYearEditFieldLabel.Text = 'Epoch Year';
            app.EpochDayEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.EpochDayEditFieldLabel.Text = 'Epoch Day';
            app.TLEDataLine1EpochDayEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1EpochDayEditField.Limits = [0 366.25];
            app.TLEDataLine1EpochDayEditField.ValueDisplayFormat = '%12.8f';
            app.TLEDataLine1EpochDayEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1EpochDayEditFieldValueChanged, true);
            app.TLEDataLine1EpochDayEditField.Editable = 'off';
            app.TLEDataLine1EpochYearEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1EpochYearEditField.Limits = [1957 2056];
            app.TLEDataLine1EpochYearEditField.RoundFractionalValues = 'on';
            app.TLEDataLine1EpochYearEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1EpochYearEditFieldValueChanged, true);
            app.TLEDataLine1EpochYearEditField.Editable = 'off';
            app.TLEDataLine1EpochYearEditField.Value = 1957;
            app.FirstDerivofMeanMotionEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.FirstDerivofMeanMotionEditFieldLabel.Text = 'First Deriv. of Mean Motion';
            app.SecondDerivofMeanMotionEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.SecondDerivofMeanMotionEditFieldLabel.Text = 'Second Deriv. of Mean-Motion';
            app.BallisticCoefficientB1r_EarthEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.BallisticCoefficientB1r_EarthEditFieldLabel.Text = 'Ballistic Coefficient B* (1/[r_Earth])';
            app.TLEDataLine1LaunchPieceEditField = uieditfield(app.TLEDataLine1PanelLayout, 'text');
            app.TLEDataLine1LaunchPieceEditField.CharacterLimits = [0 3];
            app.TLEDataLine1LaunchPieceEditField.InputType = 'letters';
            app.TLEDataLine1LaunchPieceEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1LaunchPieceEditFieldValueChanged, true);
            app.TLEDataLine1LaunchPieceEditField.Editable = 'off';
            app.TLEDataLine1LaunchNumEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1LaunchNumEditField.Limits = [0 999];
            app.TLEDataLine1LaunchNumEditField.RoundFractionalValues = 'on';
            app.TLEDataLine1LaunchNumEditField.ValueDisplayFormat = '%3d';
            app.TLEDataLine1LaunchNumEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1LaunchNumEditFieldValueChanged, true);
            app.TLEDataLine1LaunchNumEditField.Editable = 'off';
            app.TLEDataLine1LaunchYrEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1LaunchYrEditField.Limits = [0 99];
            app.TLEDataLine1LaunchYrEditField.RoundFractionalValues = 'on';
            app.TLEDataLine1LaunchYrEditField.ValueDisplayFormat = '%d';
            app.TLEDataLine1LaunchYrEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1LaunchYrEditFieldValueChanged, true);
            app.TLEDataLine1LaunchYrEditField.Editable = 'off';
            app.TLEDataLine1ClassificationEditField = uieditfield(app.TLEDataLine1PanelLayout, 'text');
            app.TLEDataLine1ClassificationEditField.CharacterLimits = [1 1];
            app.TLEDataLine1ClassificationEditField.InputType = 'letters';
            app.TLEDataLine1ClassificationEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1ClassificationEditFieldValueChanged, true);
            app.TLEDataLine1ClassificationEditField.Editable = 'off';
            app.ElementSetNumberEditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.ElementSetNumberEditFieldLabel.Text = 'Element Set Number';
            app.Checksummod10EditFieldLabel = uilabel(app.TLEDataLine1PanelLayout);
            app.Checksummod10EditFieldLabel.Text = 'Checksum (mod 10)';
            app.TLEDataLine1CatalogNumEditField = uieditfield(app.TLEDataLine1PanelLayout, 'numeric');
            app.TLEDataLine1CatalogNumEditField.Limits = [0 99999];
            app.TLEDataLine1CatalogNumEditField.RoundFractionalValues = 'on';
            app.TLEDataLine1CatalogNumEditField.ValueDisplayFormat = '%5d';
            app.TLEDataLine1CatalogNumEditField.ValueChangedFcn = createCallbackFcn(app, @TLEDataLine1CatalogNumEditFieldValueChanged, true);
            app.TLEDataLine1CatalogNumEditField.Editable = 'off';
            app.TLEDataTabTLELinesLayout = uigridlayout(app.TLEDataTabLayout);
            app.Line1TextEditFieldLabel = uilabel(app.TLEDataTabTLELinesLayout);
            app.Line1TextEditFieldLabel.Text = 'Line 1 Text';
            app.Line2TextEditFieldLabel = uilabel(app.TLEDataTabTLELinesLayout);
            app.Line2TextEditFieldLabel.Text = 'Line 2 Text';
            app.TLEDataTabTLELine2TextEditField = uieditfield(app.TLEDataTabTLELinesLayout, 'text');
            app.TLEDataTabTLELine2TextEditField.Editable = 'off';
            app.TLEDataTabTLELine1TextEditField = uieditfield(app.TLEDataTabTLELinesLayout, 'text');
            app.TLEDataTabTLELine1TextEditField.Editable = 'off';
            app.AutogenerateChecksumsCheckBox = uicheckbox(app.TLEDataTabLayout);
            app.AutogenerateChecksumsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutogenerateChecksumsCheckBoxValueChanged, true);
            app.AutogenerateChecksumsCheckBox.Text = 'Auto-generate Checksums';
            app.IgnoreChecksumsCheckBox = uicheckbox(app.TLEDataTabLayout);
            app.IgnoreChecksumsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreChecksumsCheckBoxValueChanged, true);
            app.IgnoreChecksumsCheckBox.Text = 'Ignore Checksums';
            app.KeplerianElementsTab = uitab(app.SatelliteInfoTabGroup);
            app.KeplerianElementsTab.Title = 'Keplerian Elements';
            app.KeplerianElementsTabLayout = uigridlayout(app.KeplerianElementsTab);
            app.InclinationdegEditField_3Label_3 = uilabel(app.KeplerianElementsTabLayout);
            app.InclinationdegEditField_3Label_3.Text = 'Inclination (deg)';
            app.KeplerianElementsTabTrueAnomalyEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabTrueAnomalyEditField.UpperLimitInclusive = 'off';
            app.KeplerianElementsTabTrueAnomalyEditField.Limits = [0 360];
            app.KeplerianElementsTabTrueAnomalyEditField.ValueDisplayFormat = '%8.4f';
            app.KeplerianElementsTabTrueAnomalyEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabTrueAnomalyEditFieldValueChanged, true);
            app.KeplerianElementsTabTrueAnomalyEditField.Editable = 'off';
            app.KeplerianElementsTabArgumentOfPerigeeEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabArgumentOfPerigeeEditField.UpperLimitInclusive = 'off';
            app.KeplerianElementsTabArgumentOfPerigeeEditField.Limits = [0 360];
            app.KeplerianElementsTabArgumentOfPerigeeEditField.ValueDisplayFormat = '%8.4f';
            app.KeplerianElementsTabArgumentOfPerigeeEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabArgumentOfPerigeeEditFieldValueChanged, true);
            app.KeplerianElementsTabArgumentOfPerigeeEditField.Editable = 'off';
            app.RAANdegEditField_3Label_3 = uilabel(app.KeplerianElementsTabLayout);
            app.RAANdegEditField_3Label_3.Text = 'RAAN (deg)';
            app.EccentricityEditField_3Label_3 = uilabel(app.KeplerianElementsTabLayout);
            app.EccentricityEditField_3Label_3.Text = 'Eccentricity';
            app.KeplerianElementsTabEccentricityEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabEccentricityEditField.Limits = [0 1];
            app.KeplerianElementsTabEccentricityEditField.ValueDisplayFormat = '%.7f';
            app.KeplerianElementsTabEccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabEccentricityEditFieldValueChanged, true);
            app.KeplerianElementsTabEccentricityEditField.Editable = 'off';
            app.KeplerianElementsTabRAANEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabRAANEditField.UpperLimitInclusive = 'off';
            app.KeplerianElementsTabRAANEditField.Limits = [0 360];
            app.KeplerianElementsTabRAANEditField.ValueDisplayFormat = '%8.4f';
            app.KeplerianElementsTabRAANEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabRAANEditFieldValueChanged, true);
            app.KeplerianElementsTabRAANEditField.Editable = 'off';
            app.ArgumentofPerigeedegLabel = uilabel(app.KeplerianElementsTabLayout);
            app.ArgumentofPerigeedegLabel.Text = 'Arg of Perigee (deg)';
            app.KeplerianElementsTabInclinationEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabInclinationEditField.Limits = [0 180];
            app.KeplerianElementsTabInclinationEditField.ValueDisplayFormat = '%8.4f';
            app.KeplerianElementsTabInclinationEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabInclinationEditFieldValueChanged, true);
            app.KeplerianElementsTabInclinationEditField.Editable = 'off';
            app.TrueAnomalydegLabel = uilabel(app.KeplerianElementsTabLayout);
            app.TrueAnomalydegLabel.Text = 'True Anomaly (deg)';
            app.SemimajorAxiskmEditFieldLabel_2 = uilabel(app.KeplerianElementsTabLayout);
            app.SemimajorAxiskmEditFieldLabel_2.Text = 'Semimajor Axis (km)';
            app.KeplerianElementsTabSemimajorAxisEditField = uieditfield(app.KeplerianElementsTabLayout, 'numeric');
            app.KeplerianElementsTabSemimajorAxisEditField.LowerLimitInclusive = 'off';
            app.KeplerianElementsTabSemimajorAxisEditField.UpperLimitInclusive = 'off';
            app.KeplerianElementsTabSemimajorAxisEditField.Limits = [6400 125000];
            app.KeplerianElementsTabSemimajorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @KeplerianElementsTabSemimajorAxisEditFieldValueChanged, true);
            app.KeplerianElementsTabSemimajorAxisEditField.Editable = 'off';
            app.KeplerianElementsTabSemimajorAxisEditField.Value = 6401;
            app.EpochUTCDatePickerLabel_2 = uilabel(app.SatelliteInfoPanelLayout);
            app.EpochUTCDatePickerLabel_2.Text = 'Epoch (UTC)';
            app.SatelliteInfoEpochDatePicker = uidatepicker(app.SatelliteInfoPanelLayout);
            app.SatelliteInfoEpochDatePicker.Editable = 'off';
            app.SatelliteInfoEpochDatePicker.ValueChangedFcn = createCallbackFcn(app, @SatelliteInfoEpochDatePickerValueChanged, true);
            app.SatelliteNameEditFieldLabel = uilabel(app.SatelliteInfoPanelLayout);
            app.SatelliteNameEditFieldLabel.Text = 'Satellite Name';
            app.SatelliteInfoNameEditField = uieditfield(app.SatelliteInfoPanelLayout, 'text');
            app.SatelliteInfoNameEditField.ValueChangedFcn = createCallbackFcn(app, @SatelliteInfoNameEditFieldValueChanged, true);
            app.SatelliteInfoNameEditField.Editable = 'off';
            app.SatelliteTabUIHorizontalSlider = uislider(app.SatelliteTabLayout);
            app.SatelliteTabUIHorizontalSlider.Limits = [0 1];
            app.SatelliteTabUIHorizontalSlider.MajorTicks = [];
            app.SatelliteTabUIHorizontalSlider.MajorTickLabels = {''};
            app.SatelliteTabUIHorizontalSlider.MinorTicks = [];
            app.SatelliteTabUIHorizontalSlider.Value = 0.4;
            app.SatelliteTabUIVerticalSlider = uislider(app.SatelliteTabLayout);
            app.SatelliteTabUIVerticalSlider.Limits = [0 1];
            app.SatelliteTabUIVerticalSlider.MajorTicks = [];
            app.SatelliteTabUIVerticalSlider.MajorTickLabels = {};
            app.SatelliteTabUIVerticalSlider.MinorTicks = [];
            app.SatelliteTabUIVerticalSlider.Value = 0.6;
            app.SatelliteTabStatusLabel = uilabel(app.SatelliteTabLayout);
            app.SatelliteTabStatusLabel.Text = '';
            app.LoadedSatellitesPanel = uipanel(app.SatelliteTabLayout);
            app.LoadedSatellitesPanel.Title = 'Loaded Satellites';
            app.LoadedSatellitesPanelLayout = uigridlayout(app.LoadedSatellitesPanel);
            app.LoadedSatellitesTable = uitable(app.LoadedSatellitesPanelLayout);
            app.LoadedSatellitesTable.ColumnName = {'ID'; 'Name'; 'Catalog Number'; 'Mean Motion (rev/day)'; 'Semimajor axis (km)'; 'Inclination (deg)'; 'RAAN (deg)'; 'Eccentricity'; 'Arg of Perigee (deg)'; 'Mean Anomaly (deg)'; 'B-Star (1/[r_Earth])'; 'Epoch (UTC)'; 'TLE Line 1'; 'TLE Line 2'};
            app.LoadedSatellitesTable.ColumnRearrangeable = 'on';
            app.LoadedSatellitesTable.RowName = {};
            app.LoadedSatellitesTable.ColumnSortable = [true true true true true true true];
            app.LoadedSatellitesTable.SelectionType = 'row';
            app.LoadedSatellitesTable.CellSelectionCallback = createCallbackFcn(app, @LoadedSatellitesTableCellSelection, true);
            app.LoadedSatellitesTable.SelectionChangedFcn = createCallbackFcn(app, @LoadedSatellitesTableSelectionChanged, true);
            app.LoadedSatellitesTable.Multiselect = 'off';
            app.AddSatellitePanel = uipanel(app.SatelliteTabLayout);
            app.AddSatellitePanel.Title = 'Add Satellite';
            app.AddSatellitePanelLayout = uigridlayout(app.AddSatellitePanel);
            app.AddSatelliteTabGroup = uitabgroup(app.AddSatellitePanelLayout);
            app.AddSatelliteFromTLETab = uitab(app.AddSatelliteTabGroup);
            app.AddSatelliteFromTLETab.Title = 'TLE';
            app.AddSatelliteFromTLETabLayout = uigridlayout(app.AddSatelliteFromTLETab);
            app.TLEFileEditFieldLabel = uilabel(app.AddSatelliteFromTLETabLayout);
            app.TLEFileEditFieldLabel.Text = 'TLE File';
            app.AddSatelliteFromTLEButton = uibutton(app.AddSatelliteFromTLETabLayout, 'push');
            app.AddSatelliteFromTLEButton.ButtonPushedFcn = createCallbackFcn(app, @AddSatelliteFromTLEButtonPushed, true);
            app.AddSatelliteFromTLEButton.Enable = 'off';
            app.AddSatelliteFromTLEButton.Text = 'Add Satellite(s)';
            app.TLEFileEditField = uieditfield(app.AddSatelliteFromTLETabLayout, 'text');
            app.TLEFromFileOpenButton = uibutton(app.AddSatelliteFromTLETabLayout, 'push');
            app.TLEFromFileOpenButton.ButtonPushedFcn = createCallbackFcn(app, @TLEFromFileOpenButtonPushed, true);
            app.TLEFromFileOpenButton.Text = 'Open...';
            app.SearchTextAreaLabel = uilabel(app.AddSatelliteFromTLETabLayout);
            app.SearchTextAreaLabel.Text = 'Search';
            app.TLEFromFileSearchTextArea = uitextarea(app.AddSatelliteFromTLETabLayout);
            app.TLEFromURLLoadButton = uibutton(app.AddSatelliteFromTLETabLayout, 'push');
            app.TLEFromURLLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TLEFromURLLoadButtonPushed, true);
            app.TLEFromURLLoadButton.Text = 'Load';
            app.TLEURLEditFieldLabel = uilabel(app.AddSatelliteFromTLETabLayout);
            app.TLEURLEditFieldLabel.Text = 'TLE URL';
            app.TLEFromURLEditField = uieditfield(app.AddSatelliteFromTLETabLayout, 'text');
            app.TLEFromURLEditField.Value = 'https://www.celestrak.com/NORAD/elements/active.txt';
            app.SearchTextArea_2Label = uilabel(app.AddSatelliteFromTLETabLayout);
            app.SearchTextArea_2Label.Text = 'Search';
            app.TLEFromURLSearchTextArea = uitextarea(app.AddSatelliteFromTLETabLayout);
            app.TLETextTextAreaLabel = uilabel(app.AddSatelliteFromTLETabLayout);
            app.TLETextTextAreaLabel.Text = 'TLE Text';
            app.TLETextTextArea = uitextarea(app.AddSatelliteFromTLETabLayout);
            app.TLETextTextArea.ValueChangedFcn = createCallbackFcn(app, @TLETextTextAreaValueChanged, true);
            app.TLEFromFileExactMatchCheckBox = uicheckbox(app.AddSatelliteFromTLETabLayout);
            app.TLEFromFileExactMatchCheckBox.Text = 'Exact matches only';
            app.TLEFromURLExactMatchCheckBox = uicheckbox(app.AddSatelliteFromTLETabLayout);
            app.TLEFromURLExactMatchCheckBox.Text = 'Exact matches only';
            app.AddSatelliteFromKeplerianElementsTab = uitab(app.AddSatelliteTabGroup);
            app.AddSatelliteFromKeplerianElementsTab.Title = 'Keplerian Elements';
            app.AddSatelliteFromKeplerianElementsTabLayout = uigridlayout(app.AddSatelliteFromKeplerianElementsTab);
            app.OrbitPresetLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.OrbitPresetLabel.Text = 'Preset';
            app.SemimajorAxiskmEditFieldLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.SemimajorAxiskmEditFieldLabel.Text = 'Semi-major Axis (km)';
            app.AddSatelliteFromKeplerianElementsButton = uibutton(app.AddSatelliteFromKeplerianElementsTabLayout, 'push');
            app.AddSatelliteFromKeplerianElementsButton.ButtonPushedFcn = createCallbackFcn(app, @AddSatelliteFromKeplerianElementsButtonPushed, true);
            app.AddSatelliteFromKeplerianElementsButton.Enable = 'off';
            app.AddSatelliteFromKeplerianElementsButton.Text = 'Add Satellite';
            app.AddSatelliteFromKeplerianElementsTrueAnomaly = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsTrueAnomaly.UpperLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsTrueAnomaly.Limits = [0 360];
            app.EccentricityEditField_2Label = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.EccentricityEditField_2Label.Text = 'Eccentricity';
            app.RAANdegEditField_2Label = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.RAANdegEditField_2Label.Text = 'RAAN (deg)';
            app.ArgofPerigeedegLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.ArgofPerigeedegLabel.Text = 'Arg. of Perigee (deg)';
            app.AddSatelliteFromKeplerianElementsArgofPeriapsis = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsArgofPeriapsis.UpperLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsArgofPeriapsis.Limits = [0 360];
            app.AddSatelliteFromKeplerianElementsRAAN = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsRAAN.UpperLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsRAAN.Limits = [0 360];
            app.TrueAnomalydegEditFieldLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.TrueAnomalydegEditFieldLabel.Text = 'True Anomaly (deg)';
            app.InclinationdegEditField_2Label = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.InclinationdegEditField_2Label.Text = 'Inclination (deg)';
            app.AddSatelliteFromKeplerianElementsInclination = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsInclination.Limits = [0 180];
            app.AddSatelliteFromKeplerianElementsEccentricity = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsEccentricity.UpperLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsEccentricity.Limits = [0 1];
            app.AddSatelliteFromKeplerianElementsSemimajorAxis = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'numeric');
            app.AddSatelliteFromKeplerianElementsSemimajorAxis.LowerLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsSemimajorAxis.UpperLimitInclusive = 'off';
            app.AddSatelliteFromKeplerianElementsSemimajorAxis.Limits = [6400 120000];
            app.AddSatelliteFromKeplerianElementsSemimajorAxis.Value = 6401;
            app.AddSatelliteFromKeplerianElementsPresetDropdown = uidropdown(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.AddSatelliteFromKeplerianElementsPresetDropdown.Items = {'None', 'Sun-synchronous', 'Geostationary'};
            app.AddSatelliteFromKeplerianElementsPresetDropdown.Value = 'None';
            app.EpochUTCDatePickerLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.EpochUTCDatePickerLabel.Text = 'Epoch (UTC)';
            app.AddSatelliteFromKeplerianElementsEpochTime = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'text');
            app.AddSatelliteFromKeplerianElementsEpochTime.Value = '00:00:00';
            app.AddSatelliteFromKeplerianElementsEpochDate = uidatepicker(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.AddSatelliteFromKeplerianElementsEpochDate.Limits = [datetime([1957 1 1]) datetime([2056 12 31])];
            app.AddSatelliteFromKeplerianElementsEpochDate.ValueChangedFcn = createCallbackFcn(app, @AddSatelliteFromKeplerianElementsEpochDateValueChanged, true);
            app.NameEditFieldLabel = uilabel(app.AddSatelliteFromKeplerianElementsTabLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.AddSatelliteFromKeplerianElementsNameEditField = uieditfield(app.AddSatelliteFromKeplerianElementsTabLayout, 'text');
            app.AddSatelliteFromSGP4ElementsTab = uitab(app.AddSatelliteTabGroup);
            app.AddSatelliteFromSGP4ElementsTab.Title = 'SGP4 Elements';
            app.AddSatelliteFromSGP4ElementsTabLayout = uigridlayout(app.AddSatelliteFromSGP4ElementsTab);
            app.OrbitPresetLabel_2 = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.OrbitPresetLabel_2.Text = 'Preset';
            app.AddSatelliteFromSGP4ElementsButton = uibutton(app.AddSatelliteFromSGP4ElementsTabLayout, 'push');
            app.AddSatelliteFromSGP4ElementsButton.ButtonPushedFcn = createCallbackFcn(app, @AddSatelliteFromSGP4ElementsButtonPushed, true);
            app.AddSatelliteFromSGP4ElementsButton.Enable = 'off';
            app.AddSatelliteFromSGP4ElementsButton.Text = 'Add Satellite';
            app.AddSatelliteFromSGP4ElementsBStar = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsMeanAnomaly = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsMeanAnomaly.UpperLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsMeanAnomaly.Limits = [0 360];
            app.MeanMotionrevdayLabel = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.MeanMotionrevdayLabel.Text = 'Mean Motion (rev/day)';
            app.EccentricityEditField_3Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.EccentricityEditField_3Label.Text = 'Eccentricity';
            app.RAANdegEditField_3Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.RAANdegEditField_3Label.Text = 'RAAN (deg)';
            app.ArgofPerigeedegLabel_2 = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.ArgofPerigeedegLabel_2.Text = 'Arg. of Perigee (deg)';
            app.AddSatelliteFromSGP4ElementsArgofPeriapsis = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsArgofPeriapsis.UpperLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsArgofPeriapsis.Limits = [0 360];
            app.AddSatelliteFromSGP4ElementsRAAN = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsRAAN.UpperLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsRAAN.Limits = [0 360];
            app.MeanAnomalydegEditField_2Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.MeanAnomalydegEditField_2Label.Text = 'Mean Anomaly (deg)';
            app.InclinationdegEditField_3Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.InclinationdegEditField_3Label.Text = 'Inclination (deg)';
            app.AddSatelliteFromSGP4ElementsInclination = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsInclination.Limits = [0 180];
            app.AddSatelliteFromSGP4ElementsEccentricity = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsEccentricity.UpperLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsEccentricity.Limits = [0 1];
            app.AddSatelliteFromSGP4ElementsMeanMotion = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'numeric');
            app.AddSatelliteFromSGP4ElementsMeanMotion.LowerLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsMeanMotion.UpperLimitInclusive = 'off';
            app.AddSatelliteFromSGP4ElementsMeanMotion.Limits = [6.5 100];
            app.AddSatelliteFromSGP4ElementsMeanMotion.Value = 7.5;
            app.AddSatelliteFromSGP4ElementsPresetDropDown = uidropdown(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.AddSatelliteFromSGP4ElementsPresetDropDown.Items = {'None', 'Sun-synchronous', 'Geostationary'};
            app.AddSatelliteFromSGP4ElementsPresetDropDown.Value = 'None';
            app.EpochUTCDatePicker_2Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.EpochUTCDatePicker_2Label.Text = 'Epoch (UTC)';
            app.AddSatelliteFromSGP4ElementsEpochTime = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'text');
            app.AddSatelliteFromSGP4ElementsEpochTime.Value = '00:00:00';
            app.AddSatelliteFromSGP4ElementsEpochDate = uidatepicker(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.AddSatelliteFromSGP4ElementsEpochDate.Limits = [datetime([1957 1 1]) datetime([2056 12 31])];
            app.AddSatelliteFromSGP4ElementsEpochDate.ValueChangedFcn = createCallbackFcn(app, @AddSatelliteFromSGP4ElementsEpochDateValueChanged, true);
            app.NameEditField_2Label = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.NameEditField_2Label.Text = 'Name';
            app.B1EarthRadiusEditFieldLabel = uilabel(app.AddSatelliteFromSGP4ElementsTabLayout);
            app.B1EarthRadiusEditFieldLabel.Text = 'B* (1/[Earth Radius])';
            app.AddSatelliteFromSGP4ElementsNameEditField = uieditfield(app.AddSatelliteFromSGP4ElementsTabLayout, 'text');
            app.GroundStationsTab = uitab(app.MasterTabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.GroundStationsTabGridLayout = uigridlayout(app.GroundStationsTab);
            app.GroundStationsInfoPanel = uipanel(app.GroundStationsTabGridLayout);
            app.GroundStationsInfoPanel.Title = 'Ground Stations Info';
            app.GroundStationsInfoPanelGridLayout = uigridlayout(app.GroundStationsInfoPanel);
            app.GroundStationsInfoTable = uitable(app.GroundStationsInfoPanelGridLayout);
            app.GroundStationsInfoTable.ColumnName = {'Name'; 'Latitude (deg)'; 'Longitude (deg)'; 'Altitude (m)'; 'Min. Elevation (deg)'};
            app.GroundStationsInfoTable.RowName = {};
            app.GroundStationsInfoTable.ColumnSortable = true;
            app.GroundStationsInfoTableLoadButton = uibutton(app.GroundStationsInfoPanelGridLayout, 'push');
            app.GroundStationsInfoTableLoadButton.Text = 'Load...';
            app.GroundStationsInfoTableSaveAsButton = uibutton(app.GroundStationsInfoPanelGridLayout, 'push');
            app.GroundStationsInfoTableSaveAsButton.Text = 'Export...';
            app.PassoverOptionsPanel = uipanel(app.GroundStationsTabGridLayout);
            app.PassoverOptionsPanel.Title = 'Add Ground Station';
            app.PassoverOptionsPanelGridLayout = uigridlayout(app.PassoverOptionsPanel);
            app.PassoverOptionsCalculatePassoversButton = uibutton(app.PassoverOptionsPanelGridLayout, 'push');
            app.PassoverOptionsCalculatePassoversButton.Enable = 'off';
            app.PassoverOptionsCalculatePassoversButton.Text = 'Add Ground Station';
            app.NameEditField_3Label = uilabel(app.PassoverOptionsPanelGridLayout);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.PassoverOptionsPanelGridLayout, 'text');
            app.LatitudedegEditFieldLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.LatitudedegEditFieldLabel.Text = 'Latitude (deg)';
            app.LatitudedegEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.LongitudedegEditFieldLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.LongitudedegEditFieldLabel.Text = 'Longitude (deg)';
            app.LongitudedegEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.AltitudemEditFieldLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.AltitudemEditFieldLabel.Text = 'Altitude (m)';
            app.AltitudemEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.MinElevationdegEditFieldLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.MinElevationdegEditFieldLabel.Text = 'Min. Elevation (deg)';
            app.MinElevationdegEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.PassoverEventsTablePanel = uipanel(app.GroundStationsTabGridLayout);
            app.PassoverEventsTablePanel.Title = 'Passover Events';
            app.PassoverEventsTablePanelGridLayout = uigridlayout(app.PassoverEventsTablePanel);
            app.ShowNonPassoverWindowsCheckBox = uicheckbox(app.PassoverEventsTablePanelGridLayout);
            app.ShowNonPassoverWindowsCheckBox.Text = 'Show Non-Passover Windows';
            app.TimestampDropDownLabel_2 = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.TimestampDropDownLabel_2.Text = 'Timestamp';
            app.PassoverTableTimestampFormatDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.PassoverTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_2 = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.UTCOffsetLabel_2.Text = 'UTC Offset ±';
            app.PassoverTableUTCOffsetEditField = uieditfield(app.PassoverEventsTablePanelGridLayout, 'text');
            app.PassoverTableUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDown_2Label = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.DisplayasDropDown_2Label.Text = 'Display as';
            app.PassoverTableDisplayasDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.PassoverTableDisplayasDropDown.Value = 'event sequence';
            app.PassoverTableShowStepIndexesCheckBox = uicheckbox(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.PassoverOptionsIgnorePassoversCheckBox = uicheckbox(app.PassoverEventsTablePanelGridLayout);
            app.PassoverOptionsIgnorePassoversCheckBox.Text = 'Ignore passovers shorter than...';
            app.PassoverOptionsIgnorePassoversUnitsDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Items = {'milliseconds', 'seconds', 'minutes', 'hours', 'days'};
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Enable = 'off';
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Value = 'minutes';
            app.PassoverOptionsIgnorePassoversEditField = uieditfield(app.PassoverEventsTablePanelGridLayout, 'numeric');
            app.PassoverOptionsIgnorePassoversEditField.Limits = [0 Inf];
            app.PassoverOptionsIgnorePassoversEditField.Enable = 'off';
            app.PassoverEventsTableAddtoPlanButton = uibutton(app.PassoverEventsTablePanelGridLayout, 'push');
            app.PassoverEventsTableAddtoPlanButton.Enable = 'off';
            app.PassoverEventsTableAddtoPlanButton.Text = 'Add to Plan';
            app.PassoverEventsTableAddAllButton = uibutton(app.PassoverEventsTablePanelGridLayout, 'push');
            app.PassoverEventsTableAddAllButton.Enable = 'off';
            app.PassoverEventsTableAddAllButton.Text = 'Add All';
            app.PassoverEventsTable = uitable(app.PassoverEventsTablePanelGridLayout);
            app.PassoverEventsTable.ColumnName = {'GS Name'; 'Access #'; 'Start Time (UTC+00:00)'; 'End Time (UTC+00:00)'; 'Duration (min)'};
            app.PassoverEventsTable.RowName = {};
            app.PassoverEventsTable.ColumnSortable = true;
            app.PassoverEventsTable.ColumnEditable = [true false false false true];
            app.PassoverEventsTable.Tooltip = {'Select an even to add to plan.'};
            app.LoadButton_2 = uibutton(app.PassoverEventsTablePanelGridLayout, 'push');
            app.LoadButton_2.Text = 'Load...';
            app.ExportButton_2 = uibutton(app.PassoverEventsTablePanelGridLayout, 'push');
            app.ExportButton_2.Text = 'Export...';
            app.DisplayDropDown_2Label = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.DisplayDropDown_2Label.Text = 'Display';
            app.PassoverEventsTableSatellitesDisplayDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverEventsTableSatellitesDisplayDropDown.Items = {'All'};
            app.PassoverEventsTableSatellitesDisplayDropDown.Value = 'All';
            app.GroundStationsTabUIVerticalSlider = uislider(app.GroundStationsTabGridLayout);
            app.GroundStationsTabUIVerticalSlider.Limits = [0 1];
            app.GroundStationsTabUIVerticalSlider.MajorTicks = [];
            app.GroundStationsTabUIVerticalSlider.MajorTickLabels = {};
            app.GroundStationsTabUIVerticalSlider.MinorTicks = [];
            app.GroundStationsTabUIVerticalSlider.Value = 0.6;
            app.GroundStationsTabUIHorizontalSlider = uislider(app.GroundStationsTabGridLayout);
            app.GroundStationsTabUIHorizontalSlider.Limits = [0 1];
            app.GroundStationsTabUIHorizontalSlider.MajorTicks = [];
            app.GroundStationsTabUIHorizontalSlider.MajorTickLabels = {};
            app.GroundStationsTabUIHorizontalSlider.MinorTicks = [];
            app.GroundStationsTabUIHorizontalSlider.Value = 0.4;
            app.GroundStationsTabStatusLabel = uilabel(app.GroundStationsTabGridLayout);
            app.GroundStationsTabStatusLabel.Text = '';
            app.PropagationTab = uitab(app.MasterTabGroup);
            app.PropagationTab.Title = 'Propagation';
            app.SGP4TabGridLayout = uigridlayout(app.PropagationTab);
            app.SGP4TablePanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4TablePanel.Title = 'Propagation Data';
            app.SGP4TableGridLayout = uigridlayout(app.SGP4TablePanel);
            app.SGP4Table = uitable(app.SGP4TableGridLayout);
            app.SGP4Table.ColumnName = {'Time'; 'Coord 1'; 'Coord 2'; 'Coord 3'};
            app.SGP4Table.RowName = {};
            app.SGP4Table.ColumnSortable = true;
            app.SGP4TableShowStepIndexesCheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableShowStepIndexesCheckBox.Enable = 'off';
            app.SGP4TableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.UTCOffsetEditField_2Label = uilabel(app.SGP4TableGridLayout);
            app.UTCOffsetEditField_2Label.Text = 'UTC Offset ±';
            app.SGP4TableUTCOffsetEditField = uieditfield(app.SGP4TableGridLayout, 'text');
            app.SGP4TableUTCOffsetEditField.Value = '+00:00';
            app.CoordinatesDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.CoordinatesDropDownLabel.Text = 'Coordinates';
            app.SGP4TableCoordinatesFormatDropDown = uidropdown(app.SGP4TableGridLayout);
            app.SGP4TableCoordinatesFormatDropDown.Items = {'LLA', 'GCRF', 'ECEF'};
            app.SGP4TableCoordinatesFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SGP4TableCoordinatesFormatDropDownValueChanged, true);
            app.SGP4TableCoordinatesFormatDropDown.Value = 'LLA';
            app.TimestampDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.TimestampDropDownLabel.Text = 'Timestamp';
            app.SGP4TableTimestampFormatDropDown = uidropdown(app.SGP4TableGridLayout);
            app.SGP4TableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.SGP4TableTimestampFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SGP4TableTimestampFormatDropDownValueChanged, true);
            app.SGP4TableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.SGP4TableHighlightSAACheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableHighlightSAACheckBox.Enable = 'off';
            app.SGP4TableHighlightSAACheckBox.Text = 'Highlight Keepout';
            app.SGP4TableHighlightSAACheckBox.Value = true;
            app.SGP4TableHighlightEclipseCheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableHighlightEclipseCheckBox.Enable = 'off';
            app.SGP4TableHighlightEclipseCheckBox.Text = 'Highlight Eclipse';
            app.DisplayDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.DisplayDropDownLabel.Text = 'Display';
            app.SGP4TableSatellitesDisplayDropDown = uidropdown(app.SGP4TableGridLayout);
            app.SGP4TableSatellitesDisplayDropDown.Items = {'All'};
            app.SGP4TableSatellitesDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @SGP4TableSatellitesDisplayDropDownValueChanged, true);
            app.SGP4TableSatellitesDisplayDropDown.Value = 'All';
            app.SGP4TableLoadButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.SGP4TableLoadButton.Text = 'Load...';
            app.SGP4TableExportButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.SGP4TableExportButton.Text = 'Export...';
            app.SGP4TableAddEphemerisButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.SGP4TableAddEphemerisButton.Enable = 'off';
            app.SGP4TableAddEphemerisButton.Text = 'Add Ephemeris...';
            app.SGP4GroundtrackPlotPanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4GroundtrackPlotPanel.Title = 'TBD';
            app.SGP4PropagatorOptionsPanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4PropagatorOptionsPanel.Title = 'SGP4 Propagator Options';
            app.SGP4PropagatorOptionsPanelGridLayout = uigridlayout(app.SGP4PropagatorOptionsPanel);
            app.SGP4PropagatorPropagateButton = uibutton(app.SGP4PropagatorOptionsPanelGridLayout, 'push');
            app.SGP4PropagatorPropagateButton.ButtonPushedFcn = createCallbackFcn(app, @SGP4PropagatorPropagateButtonPushed, true);
            app.SGP4PropagatorPropagateButton.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorPropagateButton.Enable = 'off';
            app.SGP4PropagatorPropagateButton.Text = 'Propagate';
            app.TimestepEditFieldLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.TimestepEditFieldLabel.Tag = 'SGP4NewPropagationOptions';
            app.TimestepEditFieldLabel.Text = 'Timestep';
            app.SGP4PropagatorTimestepEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'numeric');
            app.SGP4PropagatorTimestepEditField.LowerLimitInclusive = 'off';
            app.SGP4PropagatorTimestepEditField.UpperLimitInclusive = 'off';
            app.SGP4PropagatorTimestepEditField.Limits = [0 Inf];
            app.SGP4PropagatorTimestepEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorTimestepEditField.Value = 5;
            app.SGP4PropagatorEndTimeEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorEndTimeEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorEndTimeEditField.Value = '00:00:00';
            app.SGP4PropagatorStartTimeEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorStartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SGP4PropagatorStartTimeEditFieldValueChanged, true);
            app.SGP4PropagatorStartTimeEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorStartTimeEditField.Value = '00:00:00';
            app.SGP4PropagatorTimestepUnitsDropDown = uidropdown(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorTimestepUnitsDropDown.Items = {'milliseconds', 'seconds', 'minutes', ''};
            app.SGP4PropagatorTimestepUnitsDropDown.Value = 'seconds';
            app.EndTimeUTCLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.EndTimeUTCLabel.Text = 'End Time (UTC)';
            app.SGP4PropagatorEndDateDatePicker = uidatepicker(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorEndDateDatePicker.DisplayFormat = 'MMM-dd-yyyy';
            app.SGP4PropagatorEndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SGP4PropagatorEndDateDatePickerValueChanged, true);
            app.StartTimeUTCLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.StartTimeUTCLabel.Text = 'Start Time (UTC)';
            app.SGP4PropagatorStartDateDatePicker = uidatepicker(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorStartDateDatePicker.DisplayFormat = 'MMM-dd-yyyy';
            app.SGP4PropagatorStartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SGP4PropagatorStartDateDatePickerValueChanged, true);
            app.UTCOffsetLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.UTCOffsetLabel.Text = 'UTC Offset ±';
            app.SGP4PropagatorUTCOffsetEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorUTCOffsetEditField.Value = '+00:00';
            app.PropagatorDropDownLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.PropagatorDropDownLabel.Text = 'Propagator';
            app.PropagatorDropDown = uidropdown(app.SGP4PropagatorOptionsPanelGridLayout);
            app.PropagatorDropDown.Items = {'SGP4', 'SDP4', 'Two-Body Keplerian'};
            app.PropagatorDropDown.Value = 'SGP4';
            app.SGP4TabUIVerticalSlider = uislider(app.SGP4TabGridLayout);
            app.SGP4TabUIVerticalSlider.Limits = [0 1];
            app.SGP4TabUIVerticalSlider.MajorTicks = [];
            app.SGP4TabUIVerticalSlider.MajorTickLabels = {};
            app.SGP4TabUIVerticalSlider.MinorTicks = [];
            app.SGP4TabUIVerticalSlider.Value = 0.6;
            app.SGP4TabStatusLabel = uilabel(app.SGP4TabGridLayout);
            app.SGP4TabStatusLabel.Text = '';
            app.TargetsTab = uitab(app.MasterTabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsTabGridLayout = uigridlayout(app.TargetsTab);
            app.TargetInfoPanel = uipanel(app.TargetsTabGridLayout);
            app.TargetInfoPanel.Title = 'Target Info';
            app.TargetInfoPanelGridLayout = uigridlayout(app.TargetInfoPanel);
            app.TargetInfoTable = uitable(app.TargetInfoPanelGridLayout);
            app.TargetInfoTable.ColumnName = {'Name'; 'SNR'; 'Scientific Factor'; 'Importance Factor'; 'Priority'};
            app.TargetInfoTable.RowName = {};
            app.TargetInfoTable.ColumnSortable = true;
            app.TargetInfoLoadButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoLoadButton.Text = 'Load...';
            app.TargetInfoSaveAsButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoSaveAsButton.Text = 'Save As...';
            app.TargetInfoDeleteButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoDeleteButton.Text = 'Delete';
            app.TargetSchedulerOptionsPanel = uipanel(app.TargetsTabGridLayout);
            app.TargetSchedulerOptionsPanel.Title = 'Target Scheduler Options';
            app.TargetSchedulerOptionsPanelGridLayout = uigridlayout(app.TargetSchedulerOptionsPanel);
            app.TargetSchedulerCalculateVisibilityButton = uibutton(app.TargetSchedulerOptionsPanelGridLayout, 'push');
            app.TargetSchedulerCalculateVisibilityButton.Enable = 'off';
            app.TargetSchedulerCalculateVisibilityButton.Text = 'Calculate Visibility';
            app.TargetOptionsIgnoreWindowsCheckBox = uicheckbox(app.TargetSchedulerOptionsPanelGridLayout);
            app.TargetOptionsIgnoreWindowsCheckBox.Text = 'Ignore observation windows shorter than...';
            app.TargetOptionsIgnoreWindowsEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsIgnoreWindowsEditField.Limits = [0 Inf];
            app.TargetOptionsIgnoreWindowsEditField.Enable = 'off';
            app.TargetOptionsIgnoreWindowsUnitsDropDown = uidropdown(app.TargetSchedulerOptionsPanelGridLayout);
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Items = {'seconds', 'minutes', 'hours', 'days'};
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Editable = 'on';
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Enable = 'off';
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Value = 'minutes';
            app.DayNightConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.DayNightConstraintEditFieldLabel.Text = 'Day/Night Constraint';
            app.TargetOptionsDayNightConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.ElevationConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.ElevationConstraintEditFieldLabel.Text = 'Elevation Constraint';
            app.TargetOptionsElevationConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsElevationConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsElevationConstraintEditField.Value = 15;
            app.MoonConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.MoonConstraintEditFieldLabel.Text = 'Moon Constraint';
            app.TargetOptionsMoonConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsMoonConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsMoonConstraintEditField.Value = 20;
            app.SunConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.SunConstraintEditFieldLabel.Text = 'Sun Constraint';
            app.TargetOptionsSunConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsSunConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsSunConstraintEditField.Value = 90;
            app.TargetObservationWindowsTablePanel = uipanel(app.TargetsTabGridLayout);
            app.TargetObservationWindowsTablePanel.Title = 'Target Observation Windows';
            app.TargetObservationWindowsTablePanelGridLayout = uigridlayout(app.TargetObservationWindowsTablePanel);
            app.TargetObservationWindowsTable = uitable(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetObservationWindowsTable.ColumnName = {'Target Name'; 'Window #'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Window Type'; 'Target Priority'; 'RA (deg)'; 'Dec (deg)'; 'ECI X'; 'ECI Y'; 'ECI Z'};
            app.TargetObservationWindowsTable.RowName = {};
            app.TargetObservationWindowsTable.ColumnSortable = [true true true true true];
            app.TargetObservationWindowsTable.ColumnEditable = [true false false false true];
            app.TimestampDropDownLabel_3 = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.TimestampDropDownLabel_3.Text = 'Timestamp';
            app.TargetWindowsTableTimestampFormatDropDown = uidropdown(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.TargetWindowsTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_3 = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.UTCOffsetLabel_3.Text = 'UTC Offset ±';
            app.TargetWindowsTableUTCOffsetEditField = uieditfield(app.TargetObservationWindowsTablePanelGridLayout, 'text');
            app.TargetWindowsTableUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDownLabel = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.DisplayasDropDownLabel.Text = 'Display as';
            app.TargetWindowsTableDisplayasDropDown = uidropdown(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.TargetWindowsTableDisplayasDropDown.Enable = 'off';
            app.TargetWindowsTableDisplayasDropDown.Value = 'event sequence';
            app.TargetWindowsTableShowStepIndexesCheckBox = uicheckbox(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.TargetsTabUIHorizontalSlider = uislider(app.TargetsTabGridLayout);
            app.TargetsTabUIHorizontalSlider.Limits = [0 1];
            app.TargetsTabUIHorizontalSlider.MajorTicks = [];
            app.TargetsTabUIHorizontalSlider.MajorTickLabels = {};
            app.TargetsTabUIHorizontalSlider.MinorTicks = [];
            app.TargetsTabUIHorizontalSlider.Value = 0.4;
            app.TargetsTabUIVerticalSlider = uislider(app.TargetsTabGridLayout);
            app.TargetsTabUIVerticalSlider.Limits = [0 1];
            app.TargetsTabUIVerticalSlider.MajorTicks = [];
            app.TargetsTabUIVerticalSlider.MajorTickLabels = {};
            app.TargetsTabUIVerticalSlider.MinorTicks = [];
            app.TargetsTabUIVerticalSlider.Value = 0.6;
            app.TargetsTabStatusLabel = uilabel(app.TargetsTabGridLayout);
            app.TargetsTabStatusLabel.Text = '';
            app.ExposuresTab = uitab(app.MasterTabGroup);
            app.ExposuresTab.Title = 'Exposures';
            app.ExposuresTabGridLayout = uigridlayout(app.ExposuresTab);
            app.ExposuresAvailableWindowsTablePanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposuresAvailableWindowsTablePanel.Title = 'Available Target Observation Windows';
            app.ExposuresAvailableWindowsTablePanelGridLayout = uigridlayout(app.ExposuresAvailableWindowsTablePanel);
            app.ExposuresAvailableWindowsTable = uitable(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableWindowsTable.ColumnName = {'Target Name'; 'Window #'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Window Type'; 'Target Priority'; 'RA (deg)'; 'Dec (deg)'; 'ECI X'; 'ECI Y'; 'ECI Z'};
            app.ExposuresAvailableWindowsTable.RowName = {};
            app.ExposuresAvailableWindowsTable.ColumnSortable = true;
            app.ExposuresAvailableIgnoreWindowsCheckBox = uicheckbox(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableIgnoreWindowsCheckBox.Text = 'Ignore windows shorter than';
            app.ExposuresAvailableIgnoreWindowsEditField = uieditfield(app.ExposuresAvailableWindowsTablePanelGridLayout, 'numeric');
            app.ExposuresAvailableIgnoreWindowsEditField.Limits = [0 Inf];
            app.ExposuresAvailableIgnoreWindowsEditField.Enable = 'off';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown = uidropdown(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Items = {'seconds', 'minutes', 'hours', 'days'};
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Editable = 'on';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Enable = 'off';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Value = 'minutes';
            app.ExposureOptionsPanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposureOptionsPanel.Title = 'Exposure Options';
            app.ExposureOptionsPanelGridLayout = uigridlayout(app.ExposureOptionsPanel);
            app.ExposureOptionsAddExposureButton = uibutton(app.ExposureOptionsPanelGridLayout, 'push');
            app.ExposureOptionsAddExposureButton.Enable = 'off';
            app.ExposureOptionsAddExposureButton.Text = 'Add Exposure';
            app.StartTimeEditField_3Label = uilabel(app.ExposureOptionsPanelGridLayout);
            app.StartTimeEditField_3Label.Text = 'Start Time';
            app.ExposureOptionsStartTimeEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.ExposureOptionsStartTimeEditField.Value = '00:00:00';
            app.TargetNameEditFieldLabel = uilabel(app.ExposureOptionsPanelGridLayout);
            app.TargetNameEditFieldLabel.Text = 'Target Name';
            app.ExposureOptionsTargetNameEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.UTCLabel = uilabel(app.ExposureOptionsPanelGridLayout);
            app.UTCLabel.Text = 'UTC ±';
            app.ExposureOptionsUTCOffsetEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.ExposureOptionsUTCOffsetEditField.Value = '+00:00';
            app.StartDateDatePickerLabel_2 = uilabel(app.ExposureOptionsPanelGridLayout);
            app.StartDateDatePickerLabel_2.Text = 'Start Date';
            app.ExposureOptionsStartDateDatePicker = uidatepicker(app.ExposureOptionsPanelGridLayout);
            app.ExposureOptionsStartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ExposuresAddedTablePanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposuresAddedTablePanel.Title = 'Exposures & Target Poiting';
            app.ExposuresAddedTablePanelGridLayout = uigridlayout(app.ExposuresAddedTablePanel);
            app.ExposuresAddedTable = uitable(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedTable.ColumnName = {'Target Name'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Priority (IT/OT)'; 'RA (deg)'; 'Dec (deg)'; 'u_x'; 'u_y'; 'u_z'; 'Target Idx'};
            app.ExposuresAddedTable.RowName = {};
            app.ExposuresAddedTable.ColumnSortable = true;
            app.ExposuresAddedTableRemoveButton = uibutton(app.ExposuresAddedTablePanelGridLayout, 'push');
            app.ExposuresAddedTableRemoveButton.Enable = 'off';
            app.ExposuresAddedTableRemoveButton.Text = 'Remove';
            app.ExposuresAddedShowStepIndexesCheckBox = uicheckbox(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedShowStepIndexesCheckBox.Text = 'Show step indexes';
            app.TimestampDropDownLabel_4 = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.TimestampDropDownLabel_4.Text = 'Timestamp';
            app.ExposuresAddedTimestampFormatDropDown = uidropdown(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.ExposuresAddedTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_4 = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.UTCOffsetLabel_4.Text = 'UTC Offset ±';
            app.ExposuresAddedUTCOffsetEditField = uieditfield(app.ExposuresAddedTablePanelGridLayout, 'text');
            app.ExposuresAddedUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDown_3Label = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.DisplayasDropDown_3Label.Text = 'Display as';
            app.ExposuresAddedDisplayasDropDown = uidropdown(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.ExposuresAddedDisplayasDropDown.Value = 'event sequence';
            app.Slider = uislider(app.ExposuresTabGridLayout);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.Slider.Value = 0.6;
            app.Slider_2 = uislider(app.ExposuresTabGridLayout);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 0.5;
            app.ExposuresTabStatusLabel = uilabel(app.ExposuresTabGridLayout);
            app.ExposuresTabStatusLabel.Text = '';
            app.ConflictResolutionTab = uitab(app.MasterTabGroup);
            app.ConflictResolutionTab.Title = 'Conflict Resolution';
            app.ConflictResolutionTabGridLayout = uigridlayout(app.ConflictResolutionTab);
            app.ConflictsPanel = uipanel(app.ConflictResolutionTabGridLayout);
            app.ConflictsPanel.Title = 'Conflicts';
            app.ConflictResolutionUnderConstructionPanelGridLayout = uigridlayout(app.ConflictsPanel);
            app.ConflictTable = uitable(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.ConflictTable.RowName = {};
            app.ConflictTable.ColumnSortable = true;
            app.ConflictTableDeleteEventButton = uibutton(app.ConflictResolutionUnderConstructionPanelGridLayout, 'push');
            app.ConflictTableDeleteEventButton.Enable = 'off';
            app.ConflictTableDeleteEventButton.Text = 'Delete Event';
            app.ConflictTableShowStepIndexesCheckBox = uicheckbox(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.TimestampDropDownLabel_5 = uilabel(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.TimestampDropDownLabel_5.Text = 'Timestamp';
            app.ConflictTableTimestampFormatDropDown = uidropdown(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.ConflictTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_5 = uilabel(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.UTCOffsetLabel_5.Text = 'UTC Offset ±';
            app.ConflictTableUTCOffsetEditField = uieditfield(app.ConflictResolutionUnderConstructionPanelGridLayout, 'text');
            app.ConflictTableUTCOffsetEditField.Value = '+00:00';
            app.FinalizePlanTab = uitab(app.MasterTabGroup);
            app.FinalizePlanTab.Title = 'Finalize Plan';
            app.FinalizePlanTabGridLayout = uigridlayout(app.FinalizePlanTab);
            app.FinalizePlanPlotPanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanPlotPanel.Title = 'Plot View';
            app.FinalizePlanTablePanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanTablePanel.Title = 'Full Plan Table (Events)';
            app.FinalizePlanTablePanelGridLayout = uigridlayout(app.FinalizePlanTablePanel);
            app.FinalizePlanRemoveButton = uibutton(app.FinalizePlanTablePanelGridLayout, 'push');
            app.FinalizePlanRemoveButton.Enable = 'off';
            app.FinalizePlanRemoveButton.Text = 'Remove Event';
            app.FinalizePlanUITable = uitable(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanUITable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.FinalizePlanUITable.RowName = {};
            app.FinalizePlanUITable.ColumnSortable = true;
            app.DisplayplanasDropDownLabel = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.DisplayplanasDropDownLabel.Text = 'Display plan as';
            app.FinalizePlanDisplayplanasDropDown = uidropdown(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanDisplayplanasDropDown.Items = {'event sequence', 'timesteps'};
            app.FinalizePlanDisplayplanasDropDown.Value = 'event sequence';
            app.TimestampDropDownLabel_6 = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.TimestampDropDownLabel_6.Text = 'Timestamp';
            app.FinalizePlanTimestampFormatDropDown = uidropdown(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.FinalizePlanTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.FinalizePlanShowUnallocatedTimesCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanShowUnallocatedTimesCheckBox.Enable = 'off';
            app.FinalizePlanShowUnallocatedTimesCheckBox.Text = 'Show Unallocated Times';
            app.UTCOffsetEditField_2Label_2 = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.UTCOffsetEditField_2Label_2.Text = 'UTC Offset ±';
            app.FinalizePlanUTCOffsetEditField = uieditfield(app.FinalizePlanTablePanelGridLayout, 'text');
            app.FinalizePlanUTCOffsetEditField.Value = '+00:00';
            app.FinalizePlanShowStepIndexesCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.AddChargingEventsCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.AddChargingEventsCheckBox.Text = 'Add Charging Events';
            app.AddChargingEventsCheckBox.Value = true;
            app.FinalizePlanViewOptionsPanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanViewOptionsPanel.Title = 'View Options';
            app.FinalizePlanUIVerticalSlider = uislider(app.FinalizePlanTabGridLayout);
            app.FinalizePlanUIVerticalSlider.Limits = [0 1];
            app.FinalizePlanUIVerticalSlider.MajorTicks = [];
            app.FinalizePlanUIVerticalSlider.MajorTickLabels = {};
            app.FinalizePlanUIVerticalSlider.MinorTicks = [];
            app.FinalizePlanUIVerticalSlider.Value = 0.25;
            app.FinalizePlanUIHorizontalSlider = uislider(app.FinalizePlanTabGridLayout);
            app.FinalizePlanUIHorizontalSlider.Limits = [0 1];
            app.FinalizePlanUIHorizontalSlider.MajorTicks = [];
            app.FinalizePlanUIHorizontalSlider.MajorTickLabels = {};
            app.FinalizePlanUIHorizontalSlider.MinorTicks = [];
            app.FinalizePlanUIHorizontalSlider.Value = 0.5;
            app.ExportJSONTab = uitab(app.MasterTabGroup);
            app.ExportJSONTab.Title = 'Export JSON';
            app.ExportJSONTabGridLayout = uigridlayout(app.ExportJSONTab);
            app.ExportJSONPanel = uipanel(app.ExportJSONTabGridLayout);
            app.ExportJSONPanel.Title = 'Export JSON';
            app.ExportJSONPanelGridLayout = uigridlayout(app.ExportJSONPanel);
            app.AuthorsEditFieldLabel = uilabel(app.ExportJSONPanelGridLayout);
            app.AuthorsEditFieldLabel.Text = 'Author(s)';
            app.ExportJSONAuthorsEditField = uieditfield(app.ExportJSONPanelGridLayout, 'text');
            app.ObservationPlanCommentsoptionalTextAreaLabel = uilabel(app.ExportJSONPanelGridLayout);
            app.ObservationPlanCommentsoptionalTextAreaLabel.Text = 'Observation Plan Comments (optional)';
            app.ExportJSONPlanCommentsoptionalTextArea = uitextarea(app.ExportJSONPanelGridLayout);
            app.ExportJSONButton = uibutton(app.ExportJSONPanelGridLayout, 'push');
            app.ExportJSONButton.Enable = 'off';
            app.ExportJSONButton.Text = 'Export';
            app.ExportJSONPreviewPanel = uipanel(app.ExportJSONTabGridLayout);
            app.ExportJSONPreviewPanel.Title = 'JSON Preview';
            app.ExportJSONPreviewGridLayout = uigridlayout(app.ExportJSONPreviewPanel);
            app.StoredCommandCommentsoptionalLabel = uilabel(app.ExportJSONPreviewGridLayout);
            app.StoredCommandCommentsoptionalLabel.Text = 'Stored Command Comments (optional)';
            app.ExportJSONCommandCommentsTextArea = uitextarea(app.ExportJSONPreviewGridLayout);
            app.ExportJSONPreview = uitextarea(app.ExportJSONPreviewGridLayout);
            app.StoredCommandDescriptionoptionalTextAreaLabel = uilabel(app.ExportJSONPreviewGridLayout);
            app.StoredCommandDescriptionoptionalTextAreaLabel.Text = 'Stored Command Description (optional)';
            app.ExportJSONCommandDescriptionTextArea = uitextarea(app.ExportJSONPreviewGridLayout);
            app.ExportJSONUIVerticalSlider = uislider(app.ExportJSONTabGridLayout);
            app.ExportJSONUIVerticalSlider.Limits = [0 1];
            app.ExportJSONUIVerticalSlider.MajorTicks = [];
            app.ExportJSONUIVerticalSlider.MajorTickLabels = {};
            app.ExportJSONUIVerticalSlider.MinorTicks = [];
            app.ExportJSONUIVerticalSlider.Value = 0.333;
            app.MasterUIScaleSlider = uislider(app.MasterGridLayout);
            app.MasterUIScaleSlider.Limits = [0 1];
            app.MasterUIScaleSlider.MajorTicks = [];
            app.MasterUIScaleSlider.MajorTickLabels = {};
            app.MasterUIScaleSlider.MinorTicks = [];
            app.MasterUIScaleSlider.Value = 0.75;
            app.MasterPlanOverviewPanel = uipanel(app.MasterGridLayout);
            app.MasterPlanOverviewPanel.Title = 'Plan Overview';
            app.MasterPlanGridLayout = uigridlayout(app.MasterPlanOverviewPanel);
            app.MasterPlanTable = uitable(app.MasterPlanGridLayout);
            app.MasterPlanTable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.MasterPlanTable.RowName = {};
            app.MasterPlanTable.ColumnSortable = true;
            app.EventConsoleLogTextArea = uitextarea(app.MasterPlanGridLayout);
            app.EventConsoleLogTextArea.Editable = 'off';
            app.MasterPlanRemoveButton = uibutton(app.MasterPlanGridLayout, 'push');
            app.MasterPlanRemoveButton.Enable = 'off';
            app.MasterPlanRemoveButton.Text = 'Remove Event';
            app.ClearButton = uibutton(app.MasterPlanGridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.VerboseCheckBox = uicheckbox(app.MasterPlanGridLayout);
            app.VerboseCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerboseCheckBoxValueChanged, true);
            app.VerboseCheckBox.Text = 'Verbose';
            app.LoadedSatellitesTableContextMenu = uicontextmenu(app.UIFigure);
            app.MoveMenu = uimenu(app.LoadedSatellitesTableContextMenu);
            app.MoveMenu.Enable = 'off';
            app.MoveMenu.Text = 'Move';
            app.UpMenu = uimenu(app.MoveMenu);
            app.UpMenu.Text = 'Up';
            app.DownMenu = uimenu(app.MoveMenu);
            app.DownMenu.Text = 'Down';
            app.FirstMenu = uimenu(app.MoveMenu);
            app.FirstMenu.Separator = 'on';
            app.FirstMenu.Text = 'First';
            app.LastMenu = uimenu(app.MoveMenu);
            app.LastMenu.Text = 'Last';
            app.DeleteMenu = uimenu(app.LoadedSatellitesTableContextMenu);
            app.DeleteMenu.Text = 'Delete';
            app.ExportMenu = uimenu(app.LoadedSatellitesTableContextMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Enable = 'off';
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Text = 'Export...';
            app.ExportAllMenu = uimenu(app.LoadedSatellitesTableContextMenu);
            app.ExportAllMenu.Text = 'Export All...';
            % Assign app.LoadedSatellitesTableContextMenu
            app.LoadedSatellitesTable.ContextMenu = app.LoadedSatellitesTableContextMenu;
            app.ConsoleLogContextMenu = uicontextmenu(app.UIFigure);
            app.ShowMenu = uimenu(app.ConsoleLogContextMenu);
            app.ShowMenu.Text = 'Show';
            app.SuccessMenu = uimenu(app.ShowMenu);
            app.SuccessMenu.Checked = 'on';
            app.SuccessMenu.Text = 'Success';
            app.WarningMenu = uimenu(app.ShowMenu);
            app.WarningMenu.Checked = 'on';
            app.WarningMenu.Text = 'Warning';
            app.ErrorMenu = uimenu(app.ShowMenu);
            app.ErrorMenu.Checked = 'on';
            app.ErrorMenu.Text = 'Error';
            app.ClearMenu = uimenu(app.ConsoleLogContextMenu);
            app.ClearMenu.Text = 'Clear';
            app.SuccessMenu_2 = uimenu(app.ClearMenu);
            app.SuccessMenu_2.Text = 'Success';
            app.WarningMenu_2 = uimenu(app.ClearMenu);
            app.WarningMenu_2.Text = 'Warning';
            app.ErrorMenu_2 = uimenu(app.ClearMenu);
            app.ErrorMenu_2.Text = 'Error';
            app.AllMenu = uimenu(app.ClearMenu);
            app.AllMenu.Separator = 'on';
            app.AllMenu.Text = 'All';
            app.VerboseMenu = uimenu(app.ConsoleLogContextMenu);
            app.VerboseMenu.Checked = 'on';
            app.VerboseMenu.Text = 'Verbose';
            app.ExportMenu_2 = uimenu(app.ConsoleLogContextMenu);
            app.ExportMenu_2.Separator = 'on';
            app.ExportMenu_2.Text = 'Export...';
            % Assign app.ConsoleLogContextMenu
            app.EventConsoleLogTextArea.ContextMenu = app.ConsoleLogContextMenu;
            app.TLETextAreaContextMenu = uicontextmenu(app.UIFigure);
            app.ExportMenu_3 = uimenu(app.TLETextAreaContextMenu);
            app.ExportMenu_3.Text = 'Export...';
            % Assign app.TLETextAreaContextMenu
            app.TLETextTextArea.ContextMenu = app.TLETextAreaContextMenu;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AutogeneratechecksumsMenu = uimenu(app.ContextMenu);
            app.AutogeneratechecksumsMenu.Checked = 'on';
            app.AutogeneratechecksumsMenu.Text = 'Auto-generate checksums';
            app.IgnorechecksumsMenu = uimenu(app.ContextMenu);
            app.IgnorechecksumsMenu.Text = 'Ignore checksums';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = satplan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end